classdef Algorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LHTAlgorithmenauswahlUIFigure   matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        AlgorithmensuffixEditField      matlab.ui.control.EditField
        AlgorithmensuffixEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        AlgorithmentypButtonGroup       matlab.ui.container.ButtonGroup
        BioinspirierterAlgorithmusButton  matlab.ui.control.ToggleButton
        HeuristischerAlgorithmusButton  matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
            % Inflated by the fill_all_heuristics() method.
            all_heuristics;
            % Options for the heuristics
            heur_opts = cell.empty(10, 0);
            % Options for bio-inspired algorithms
            bio_opts = cell.empty(10, 0);
            % Specific options for bio-inspired algorithms
            bio_algorithm_opts;
            % Callback that is executed when the algorithm has been configured.
            % Algorithm is passed as one and only argument to the callback.
            done_callback;
            % Full path to "algorithms.txt"
            algfile;
    end
    
    methods (Static, Access = private)
        function structure = heur_struct(name, handle, opts)
            %% Create a structure for a heuristic algorithm.
            %
            %   Convenience function for creating a data structure that manages
            %   a heuristic algorithm. Refer to the method fill_all_heuristics()
            %   for information on how to use it.
            %
            %   This structure serves as an abstraction to automatically create
            %   the "upper part" of the algorithm selection UI. It saves the
            %   user from manually manipulating the UI-generating code when
            %   parameters change or algorithms are added. The structures
            %   created with this method are instead parsed, and the UI is
            %   created automatically.
            %
            %   Parameters
            %   ----------
            %   name: string
            %       The name of the heuristic algorithm that is displayed in the
            %       UI dropdown list.
            %   handle: string
            %       The name of the "first part" of the function handle as it is
            %       written in heuristics.txt. For the simultaneous cluster
            %       algorithms, for example, it is 'simClust'. The case doesn't
            %       matter here, but it should conform to what's in
            %       heuristics.txt. The expression passed here will be used in
            %       conjunction with regexp() to filter for the fitting
            %       heuristic.
            %   opts: struct
            %       Zero or more structures as created by the method make_opt()
            %       that define parameters that can be passed to the base
            %       function of the heuristic that is defined.
            %
            %   Returns
            %   -------
            %   structure: struct
            %       A "formatted" struct that can be parsed by the function that
            %       generate the UI elements to configure heuristic algorithms.
            %       The names of the fields in the struct match the names of the
            %       parameters described above.
            structure = struct(...
                "name", name, ...
                "handle", handle, ...
                "options", opts);
            return;
        end
        
        function structure = make_opt(name, opts, description, chosen)
            %% Create a structure to manage options to heuristics.
            %
            %   Convenience function that generates structures that define
            %   options for heuristic algorithms defined by the heur_struct
            %   function above. Refer to the method fill_all_heuristics() for
            %   usage with existing algorithms.
            %
            %   Each of these structs is converted (automatically) to a
            %   corresponding uidropdown object below the algorithm which it is
            %   attached to. Refer to the parameters for how to generate these.
            %
            %   Parameters
            %   ----------
            %   name: string
            %       The name/title of the option. This is displayed in the Label
            %       above the uidropdown. It should explain the user what they
            %       are selecting.
            %   opts: array of strings
            %       An array of two or more strings that define the options the
            %       user may chose. The 'opts' here should correspond to parts
            %       of the function handle that can be uniquely identified and
            %       that differ between algorithms. These options will be used
            %       to filter for the algorithms to select when the user presses
            %       "OK". Refer to the method fill_all_heuristics() for
            %       additional information.
            %   description: array of strings
            %       An array with as many elements as 'opts', that contains
            %       clear-text description of the options the user may select.
            %       The strings defined in here are shown to the user in the
            %       uidropdown, so they should be readable and give the user a
            %       hint what an option does exactly.
            %   chosen: string (optional)
            %       The default value to display in the uidropdown. Must be an
            %       element of 'opts'. If nothing is provided, the value in
            %       'opts(1)' is used automatically.
            %
            %   Returns
            %   -------
            %   structure: struct
            %       A structure that is added to structures created by
            %       heur_struct() that defines an option for a heuristic.
            %       The names of the fields in the struct match the names of the
            %       parameters described above.
            if nargin < 4
                chosen = opts(1);
            end
            structure = struct(...
                "name", name, ...
                "options", opts, ...
                "description", description, ...
                "chosen", chosen);
        end
    end
    
    methods (Access = private)
        function fill_all_heuristics(app)
            %% Populate the all_heuristics variable
            %
            %   Adds contents to the all_heuristics member variable of this
            %   class. While the definitions below are mainly constant, the
            %   'chosen' attribute in the algorithm option structs prohibts us
            %   from defining these as constant directly. Thus we do the
            %   initialization here and then assign the results.
            
            % Zickzack Algorithmus
            % Hat keine Optionen!
            heur_zz = lhp.gui.Algorithm.heur_struct(...
                "Zickzack (Legacy)", ...
                'zickzack', ...
                []);
            % Sukzessive Clusterverfahren
            heur_succ = lhp.gui.Algorithm.heur_struct(...
                "Sukzessive Clusterverfahren", ...
                'sucClust', ...
                [ lhp.gui.Algorithm.make_opt(...
                    "Kandidatenauswahl", ...
                    ["NW", "LMmax", "LMmin"], ...
                    ["Kleinster Index", "Maximale Laubmenge", "Minimale Laubmenge"]), ...
                  lhp.gui.Algorithm.make_opt(...
                    "Puffer Typ", ...
                    ["FIFO", "LIFO"], ...
                    ["Queue", "Stack"]), ...
                  lhp.gui.Algorithm.make_opt(...
                    "Cluster Auswahl", ...
                    ["NW", "LMmax", "KPmin"], ...
                    ["Kleinster Index", "Maximale Laubmenge", "Min. Entf. zum Kompost"]) ...
                ]);
            % Simultane Clusterverfahren
            heur_simc = lhp.gui.Algorithm.heur_struct(...
                "Simultane Clusterverfahren", ...
                'simClust', ...
                [ lhp.gui.Algorithm.make_opt(...
                    "Zuweisungskandidat", ...
                    ["NW", "LMmax", "LMmin", "KPmin"], ...
                    ["Kleinster Index", "Maximale Laubmenge", ...
                        "Minimale Laubmenge", "Min. Entf. zum Kompost"]), ...
                  lhp.gui.Algorithm.make_opt(...
                    "Kontaktkandidat", ...
                    ["NW", "LMmax", "LMmin", "KPmin"], ...
                    ["Kleinster Index", "Maximale Laubmenge", ...
                        "Minimale Laubmenge", "Min. Entf. zum Kompost"]), ...
                  lhp.gui.Algorithm.make_opt(...
                    "Hub Strategie", ...
                    ["NW", "LMmax", "LMmin", "KPmin"], ...
                    ["Kleinster Index", "Maximale Laubmenge", ...
                        "Minimale Laubmenge", "Min. Entf. zum Kompost"]) ...
                ]);
            % -------------------------------------------------------------------------
            app.all_heuristics = [heur_zz, heur_succ, heur_simc];
        end
        
        function generate_heuristic(app)
            %% Generate a heuristic algorithm.
            %
            %   Populates the Panel in the UI to allow the user to create a
            %   heuristic algorithm. Algorithm selection is possible either by
            %   picking one from a list of all algorithms, or by configuring it
            %   from scratch with all parameters.
            
            % Clear all remnants that might still be painted on the Panel.
            try
                delete(app.Panel.Children.Children(3:end));
            catch
            end
            app.Panel.Title = 'Heuristik Konfigurieren';
            
            % Tabgroup for the two configuration UIs
            tg = uitabgroup(app.GridLayout);
            tg.Layout.Row = 1;
            tg.Layout.Column = 1;
            
            configure_tab = uitab(tg, 'Title', 'Auswahl');
            list_tab = uitab(tg, 'Title', 'Alle Algorithmen');
            
            %% ---------------------------------------------------------------------------
            % The list tab gets a search field and a list view of all
            % algorithms.
            % Read in the available heuristics.
            [index, ~, name] = readvars(app.algfile, 'CommentStyle', '#');
            listview = uilistbox(list_tab, ...
                'Tooltip', 'Algorithmenauswahl', ...
                'Position', [12, 12, 380, 160], ...
                'Items', name, ...
                'Visible', 'on', ...
                'ItemsData', index);
            % Search bar for filtering results
            uitextarea(list_tab, ...
                'Tooltip', 'Filtert die unten gezeigte Liste', ...
                'Position', [12, 184, 340, 24], ...
                'Visible', "on", ...
                'ValueChangedFcn', @(textarea, event) update_list(listview, textarea));
            
            function update_list(list, filter)
                new_index = cellfun(@any, regexpi(name, filter.Value));
                list.Items = name(new_index);
                list.ItemsData = index(new_index);
            end
            
            %% ---------------------------------------------------------------------------
            % The Selection tab gets a drop-down list and algorithm-specific
            % selection dialogs.
            paralg_dd = uidropdown(configure_tab, ...
                'Items', [app.all_heuristics.name], ...
                'ItemsData', 1:numel(app.all_heuristics), ...
                'Position', [12, 184, 340, 24], ...
                'ValueChangedFcn', @(dd, event) get_options(app.all_heuristics(dd.Value), dd.Value));
            % Call the callback once, so it's properly painted.
            get_options(app.all_heuristics(paralg_dd.Value), paralg_dd.Value);
            
            function get_options(chosen_heur, heur_idx)
                %% Populate options per heuristic in the UI
                %
                %   Create the UI elements needed to configure the chosen
                %   heuristic. The most recently selected options are stored in
                %   the all_algorithms structure so we can access the data later
                %   when we instantiate the algorithm.
                
                % Clear UI elements left over by other algorithms, if necessary.
                try
                    delete(configure_tab.Children(1:end-1));
                catch
                end
                DD_SPACING = 54;
                DD_HEIGHT = 24;
                % Iterate through the Structure.
                num_opts = numel(chosen_heur.options);
                for opt_idx = 1:num_opts
                    this_opt = chosen_heur.options(opt_idx);
                    % Create a new drop-down menu
                    uilabel(configure_tab, ...
                        'Text', this_opt.name, ...
                        'Position', [32, (184 + DD_HEIGHT - opt_idx * DD_SPACING), 320, DD_HEIGHT]);
                    uidropdown(configure_tab, ...
                        'Items', [this_opt.description], ...
                        'ItemsData', [this_opt.options], ...
                        'Position', [32, (184 - opt_idx * DD_SPACING), 320, DD_HEIGHT], ...
                        'UserData', struct('heur', heur_idx, 'opt', opt_idx), ...
                        'Value', chosen_heur.options(opt_idx).chosen, ...
                        'ValueChangedFcn', @(dd, event) app.update_heur_opt(dd));
                end
            end
            %% ---------------------------------------------------------------------------
            % Options to the LHT_HeuristicData constructor
            %% ---------------------------------------------------------------------------
            heur_opt_panel = uipanel(app.GridLayout, ...
                'Title', 'Allgemeine Parameter', ...
                'Scrollable', "on");
            heur_opt_panel.Layout.Row = 2;
            heur_opt_panel.Layout.Column = 1;
            
            uilabel(heur_opt_panel, ...
                'Text', 'Ergebnisse intern speichern:', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 184, 260, 24]);
            sr_button = uiswitch(heur_opt_panel, ...
                'Position', [312, 184, 60, 24], ...
                'ItemsData', {false, true}, ...
                'UserData', 1, ... % Index in app.heur_opts to store data in
                'ValueChangedFcn', @heur_con_cb);
            heur_con_cb(sr_button, []);
            
            uilabel(heur_opt_panel, ...
                'Text', 'Laubmengenoptimierung:', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 154, 260, 24]);
            lmo_button = uiswitch(heur_opt_panel, ...
                'Position', [312, 154, 60, 24], ...
                'ItemsData', {false, true}, ...
                'UserData', 2, ...
                'ValueChangedFcn', @heur_con_cb);
            heur_con_cb(lmo_button, []);
            
            function heur_con_cb(thing, ~)
                % Store ui-item values in the app.
                app.heur_opts{thing.UserData} = thing.Value;
            end
            
            %% ---------------------------------------------------------------------------
            % Confirmation button
            %% ---------------------------------------------------------------------------
            uibutton(app.Panel_2, ...
                'Position', [350, 1, 50, 20], ...
                'Text', 'OK', ...
                "ButtonPushedFcn", @(button, event) app.make_heuristic(tg.SelectedTab));
        end
        
        function make_heuristic(app, alg_tab)
            % Instantiate the given heuristic with options.
            %
            %   This method takes the heuristic and any options that are
            %   specified along with it and creates an instance of type
            %   LHT_HeuristicData from it.
            %
            %   Parameters
            %   ----------
            %   app: The matlab app
            %   alg_tab: uitab
            %       The algorithm chosen by the user. Data must be extracted
            %       from the selected UITab first.
            %   alg_options: ??
            %       The selection of which options to give to the
            %       LHT_HeuristicData constructor.
            switch (alg_tab.Title)
                case "Auswahl"
                    alg_idx = alg_tab.Children(end).Value;
                    match_str = app.all_heuristics(alg_idx).handle;
                    
                    % Collect all options from user input.
                    num_opts = numel(app.all_heuristics(alg_idx).options);
                    for opt_idx = 1:num_opts
                        match_str = sprintf("%s.%s", match_str, ...
                            app.all_heuristics(alg_idx).options(opt_idx).chosen);
                    end
                    
                    % Get the index of the chosen heuristic. We search through
                    % the heuristics.txt file.
                    
                    [~, handle, ~] = readvars(app.algfile, 'CommentStyle', '#');
                    chosen_idx = cellfun(@any, regexpi(handle, match_str));
                case "Alle Algorithmen"
                    % Get the highlighted entry from the list.
                    chosen_idx = alg_tab.Children(2).Value;
            end
            % TODO: Add the options.
            name_suffix = app.get_algo_suffix();
            if name_suffix == "."
                return;
            end
            
            [index, ~, ~] = readvars(app.algfile, 'CommentStyle', '#');
            algorithm = lhp.algo.DeterministicWrapper.gather(...
                "Range", index(chosen_idx), ...
                "NameSuffix", name_suffix, ...
                "StoreResults", app.heur_opts{1}, ...
                "OptimizeMaxLaub", app.heur_opts{2});
            
            app.done_callback(algorithm);
        end
        
        function update_heur_opt(app, dd)
            % Update chosen value for a heuristic algorithms option.
            %
            %   Given a dropdown UI element that belongs to a heuristic
            %   algorithms options, this method extracts the previously stored
            %   user data and the current value to update the last chosen option
            %   value for the given heuristic.
            %   This allows us to do two things:
            %
            %   1. We can restore the last chosen value when the user switches
            %      heuristics in between (we "remember" the last choice)
            %   2. When instantiating the heuristics we can iterate through the
            %      "chosen" members of all options to construct the search
            %      string.
            %
            %   Parameters
            %   ----------
            %   app: The Matlab app
            %   dd: uidropdown object
            %       The UI that was updated by the user input. Register this
            %       method as the 'ValueChangedFcn' callback to update the
            %       values proper, like this::
            %   
            %           'ValueChangedFcn', @(dd, event) app.update_heur_opt(dd))
            heur_idx = dd.UserData.heur;
            opt_idx = dd.UserData.opt;
            value = dd.Value;
            app.all_heuristics(heur_idx).options(opt_idx).chosen = value;
        end
        
        function generate_bio(app)
            
            % Clear all remnants that might still be painted on the Panel.
            try
                delete(app.Panel.Children.Children(3:end));
            catch
            end
            app.Panel.Title = 'Bio-inspirierte Algorithmen Konfigurieren';
            
            %% ---------------------------------------------------------------------------
            % Options to the LHT_GeneticData constructor
            %% ---------------------------------------------------------------------------
            bio_alg_panel = uipanel(app.GridLayout, ...
                'Title', 'Algorithmus auswählen', ...
                'Scrollable', "on");
            bio_alg_panel.Layout.Row = 1;
            bio_alg_panel.Layout.Column = 1;
            
            dd = uidropdown(bio_alg_panel, ...
                'Items', {'Genetischer Algorithmus', 'Bienenalgorithmus'}, ...
                'ItemsData', {'geneticAlgorithm', 'bienenalgorithmus'}, ...
                'Position', [12, 184, 340, 24], ...
                'ValueChangedFcn', @(dd, ~) update_alg_opts(app, dd, true));
            update_alg_opts(app, dd, true);
            
            uibutton(bio_alg_panel, ...
                "Position", [90, 120, 200, 24], ...
                "Text", "Algorithmenspezifische Optionen", ...
                "ButtonPushedFcn", @(~, ~) update_alg_opts(app, dd, false));
            
            function update_alg_opts(self, dd, defaults)
                % Parameters
                % :dd: dropdown that contains the currently selected algorithm
                % :defaults: If set to true, creates default algorithm
                % parameters. If set to false, will open respective GUIs
                switch (dd.Value)
                    case 'geneticAlgorithm'
                        if (defaults)
                            self.bio_algorithm_opts = ...
                                lhp.algo.stochastic.genetic.GeneticParameters();
                        else
                            lhp.algo.stochastic.genetic.GeneticParameters.gui(...
                                "Callback", @self.bio_algorithm_opts_cb, ...
                                "Modal", true);
                        end
                    case 'bienenalgorithmus'
                        if (defaults)
                            self.bio_algorithm_opts = ...
                                lhp.algo.stochastic.bee.BeeParameters();
                        else
                            lhp.algo.stochastic.bee.BeeParameters.gui(...
                                "Callback", @self.bio_algorithm_opts_cb, ...
                                "Modal", true);
                        end
                    otherwise
                        error("Don't know an algorithm '%s'!", dd.Value);
                end
            end
            
            %% ---------------------------------------------------------------------------
            % Options to the LHT_GeneticData constructor
            %% ---------------------------------------------------------------------------
            bio_opt_panel = uipanel(app.GridLayout, ...
                'Title', 'Allgemeine Parameter', ...
                'Scrollable', "on");
            bio_opt_panel.Layout.Row = 2;
            bio_opt_panel.Layout.Column = 1;
            
            uilabel(bio_opt_panel, ...
                'Text', 'Ergebnisse intern speichern:', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 184, 260, 24]);
            sr_button = uiswitch(bio_opt_panel, ...
                'Position', [312, 184, 60, 24], ...
                'ItemsData', {false, true}, ...
                'UserData', 1, ... % Index in app.heur_opts to store data in
                'ValueChangedFcn', @bio_con_cb);
            bio_con_cb(sr_button, []);
            
            uilabel(bio_opt_panel, ...
                'Text', 'Populationen abspeichern:', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 154, 260, 24]);
            sp_button = uiswitch(bio_opt_panel, ...
                'Position', [312, 154, 60, 24], ...
                'ItemsData', {false, true}, ...
                'UserData', 2, ...
                'ValueChangedFcn', @bio_con_cb);
            bio_con_cb(sp_button, []);
            
            uilabel(bio_opt_panel, ...
                'Text', 'Anz. Monte-Carlo Iterationen', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 124, 260, 24]);
            mci_edit = uieditfield(bio_opt_panel, 'numeric', ...
                'Value', 10, ...
                'Limits', [1, 1000], ...
                'LowerLimitInclusive', 'on', ...
                'UpperLimitInclusive', 'on', ...
                'Position', [312, 124, 60, 24], ...
                'UserData', 3, ...
                'ValueChangedFcn', @bio_con_cb);
            bio_con_cb(mci_edit, []);
            
            uilabel(bio_opt_panel, ...
                'Text', 'Anz. Worker-Threads', ...
                'HorizontalAlignment', 'right', ...
                'Position', [12, 94, 260, 24]);
            nwt_edit = uieditfield(bio_opt_panel, 'numeric', ...
                'Value', 0, ...
                'Limits', [0, 1000], ...
                'LowerLimitInclusive', 'on', ...
                'UpperLimitInclusive', 'on', ...
                'Position', [312, 94, 60, 24], ...
                'UserData', 4, ...
                'ValueChangedFcn', @bio_con_cb);
            bio_con_cb(nwt_edit, []);
            
            function bio_con_cb(thing, ~)
                % Store ui-item values in the app.
                app.bio_opts{thing.UserData} = thing.Value;
            end
            
            %% ---------------------------------------------------------------------------
            % Confirmation button
            %% ---------------------------------------------------------------------------
            uibutton(app.Panel_2, ...
                'Position', [350, 1, 50, 20], ...
                'Text', 'OK', ...
                "ButtonPushedFcn", @(button, event) app.make_genetic(dd.Value));
        end
        
        function make_genetic(app, type)
            import('lhp.algo.stochastic.*');
            
            switch (type)
                case 'geneticAlgorithm'
                    fhandle = @genetic.geneticAlgorithm;
                    name = "Genetisch";
                case 'bienenalgorithmus'
                    fhandle = @bee.bienenalgorithmus;
                    name = "Bienen";
            end
            name_suffix = app.get_algo_suffix();
            if name_suffix == "."
                return;
            end
            
            algorithm = lhp.algo.StochasticWrapper(...
                fhandle, name + name_suffix, app.bio_algorithm_opts, ...
                "StoreResults", app.bio_opts{1}, ...
                "StorePopulations", app.bio_opts{2}, ...
                "NumMonteCarloRuns", app.bio_opts{3}, ...
                "NumWorkers", app.bio_opts{4});
            
            app.done_callback(algorithm);
        end
        
        function suffix = get_algo_suffix(app)
            %% Perform validations on the user-entered algorithm suffix.
            %
            %   Throw errors if the user entered e.g. invalid characters. If the
            %   user entered invalid inputs, "." is returned to signal this to
            %   the caller.
            suffix = app.AlgorithmensuffixEditField.Value;
            
            % Valid chars are: a-z, A-Z, 0-9, '-' and '_'
            invalid_regex = '[^a-zA-Z_0-9-]';
            invalid = regexp(suffix, invalid_regex);
            if any(invalid)
                suffix_chars = char(suffix)';
                % IHATEMATLABIHATEMATLABIHATEMATLABIHATEMATLABIHATEMATLAB
                % Don't know a better way to transform a char array to a cell
                % array of chars. Ffs mathworks...
                message = sprintf("Ungueltige Zeichen in der Eingabe: %s", ...
                    strjoin(string(suffix_chars(invalid)), ", "));
                title = "Eingabe ungueltig!";
                uialert(app.LHTAlgorithmenauswahlUIFigure, ...
                    message, title, 'Icon', "error");
                suffix = ".";
            end
        end
    end
    
    methods (Access = public)
        function bio_algorithm_opts_cb(self, opts)
            self.bio_algorithm_opts = opts;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kvargs)
            % Start the algorithm selection app.
            % 
            %   Called on App startup. Takes multiple arguments. See below for
            %   details on the arguments.
            %
            %   Key-Value Parameters
            %   --------------------
            %   'Algorithms': LHT_BaseData, optional
            %       Optional parameter. If this is an instance of LHT_BaseData
            %       or any of its children, the UI will allow the user only to
            %       manipulate the constructor parameters for the given
            %       algorithm object. Refer to the respective child class to see
            %       which parameters that will be. Leave empty to start the
            %       regular selection dialog.
            %   'Callback': function_handle
            %       A callback function that is called when the user
            %       closes/finishes the algorithm selection dialog. This
            %       callback must accept exactly one argument, which is an
            %       arbitrarily sized vector of type LHT_BaseData. The argument
            %       that is passed to the callback is the algorithm (or multiple
            %       algorithms) that the user configured. The callback is
            %       needed because GUI apps as such can't provide return values,
            %       as they work asynchronously.
            arguments
                app (1, 1)
                kvargs.Algorithms (1, :) lhp.algo.BaseWrapper
                kvargs.Callback (1, 1) function_handle
            end
            % For easy access in subfunctions
            %%  apppath = erase(mfilename("fullpath"), "+gui/Algorithm");
            %% app.algfile = apppath + "+algo/+deterministic/algorithms.txt";
            
            %% Correction to use with Windows or Linux
            edir = ['+gui' filesep 'Algorithm'];
            apppath = erase(mfilename("fullpath"), edir);
            edir = ['+algo' filesep '+deterministic' filesep 'algorithms.txt'];
            app.algfile = [apppath edir];


            if ~isfield(kvargs, 'Algorithms')
                app.fill_all_heuristics();
                app.AlgorithmentypButtonGroupSelectionChanged([]);
            else
                fprintf("Not implemented!\n");
            end
            
            if isfield(kvargs, 'Callback')
                app.done_callback = kvargs.Callback;
            else
                app.done_callback = @disp;
            end
        end

        % Selection changed function: AlgorithmentypButtonGroup
        function AlgorithmentypButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmentypButtonGroup.SelectedObject;
            
            switch (selectedButton.Text)
                case "Heuristischer Algorithmus"
                    app.generate_heuristic();
                case "Bio-inspirierter Algorithmus"
                    app.generate_bio();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LHTAlgorithmenauswahlUIFigure = uifigure('Visible', 'off');
            app.LHTAlgorithmenauswahlUIFigure.Name = 'LHT - Algorithmenauswahl';
            app.AlgorithmentypButtonGroup = uibuttongroup(app.LHTAlgorithmenauswahlUIFigure);
            app.AlgorithmentypButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmentypButtonGroupSelectionChanged, true);
            app.AlgorithmentypButtonGroup.Title = 'Algorithmentyp';
            app.HeuristischerAlgorithmusButton = uitogglebutton(app.AlgorithmentypButtonGroup);
            app.HeuristischerAlgorithmusButton.Tooltip = {'Heuristiken arbeiten deterministisch nach einer festgelegten Strategie.'};
            app.HeuristischerAlgorithmusButton.Text = 'Heuristischer Algorithmus';
            app.HeuristischerAlgorithmusButton.Value = true;
            app.BioinspirierterAlgorithmusButton = uitogglebutton(app.AlgorithmentypButtonGroup);
            app.BioinspirierterAlgorithmusButton.Tooltip = {'Bio-inspirierte Algorithmen sind probabilistisch und an Verhalten aus der Natur angelehnt.'};
            app.BioinspirierterAlgorithmusButton.Text = 'Bio-inspirierter Algorithmus';
            app.Panel = uipanel(app.LHTAlgorithmenauswahlUIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AlgorithmensuffixEditFieldLabel = uilabel(app.GridLayout2);
            app.AlgorithmensuffixEditFieldLabel.Text = 'Algorithmensuffix';
            app.AlgorithmensuffixEditField = uieditfield(app.GridLayout2, 'text');
            app.AlgorithmensuffixEditField.Tooltip = {'Optionales Suffix fuer den Algorithmennamen. Zur besseren Unterscheidbarkeit bei mehreren unterschiedlichen Instanzen des selben Algorithmus.'};
            % Show the figure after all components are created
            app.LHTAlgorithmenauswahlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Algorithm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LHTAlgorithmenauswahlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LHTAlgorithmenauswahlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LHTAlgorithmenauswahlUIFigure)
        end
    end
end