classdef ProblemData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProblemDataerzeugenUIFigure   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ProblemDataBeschreibungPanel  matlab.ui.container.Panel
        SSpinnerLabel                 matlab.ui.control.Label
        SSpinner                      matlab.ui.control.Spinner
        SSpinner_2Label               matlab.ui.control.Label
        SSpinner_2                    matlab.ui.control.Spinner
        ZSpinnerLabel                 matlab.ui.control.Label
        ZSpinner                      matlab.ui.control.Spinner
        ZSpinner_2Label               matlab.ui.control.Label
        ZSpinner_2                    matlab.ui.control.Spinner
        StartLabel                    matlab.ui.control.Label
        KompostLabel                  matlab.ui.control.Label
        ProblemErzeugenButton         matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        GartenerzeugenTab             matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        ImportausDateiPanel           matlab.ui.container.Panel
        DateinameasdEditField         matlab.ui.control.EditField
        DateinameLabel                matlab.ui.control.Label
        ImDateimanagerwhlenButton     matlab.ui.control.Button
        ImportierenButton             matlab.ui.control.Button
        ImportausWorkspacePanel       matlab.ui.container.Panel
        VariablennameLabel            matlab.ui.control.Label
        DateinameasdEditField_2       matlab.ui.control.EditField
        ImportierenButton_2           matlab.ui.control.Button
        RandomisierterzeugenPanel     matlab.ui.container.Panel
        ZeilenSpinnerLabel            matlab.ui.control.Label
        ZeilenSpinner                 matlab.ui.control.Spinner
        SpaltenSpinnerLabel           matlab.ui.control.Label
        SpaltenSpinner                matlab.ui.control.Spinner
        MitKompostCheckBox            matlab.ui.control.CheckBox
        GartenErzeugenButton          matlab.ui.control.Button
        GartenPlotTab                 matlab.ui.container.Tab
        ManuellanpassenTab            matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        DateiMenu                     matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        AusdemWorkspaceMenu           matlab.ui.container.Menu
        AuseinerDateiMenu             matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        IndenWorkspaceMenu            matlab.ui.container.Menu
        IneineDateiMenu               matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Current gardenmatrix. This is the garden that is plotted in the UI and
        % that the user is allowed to manipulate.
        Garten;
        % Current ProblemData-object.
        PData;
        % Members of the ProblemData-object that should be shown in the GUI.
        PDataMembers;
        % Callback function handle. This callback is executed when the user
        % confirms the ProblemData object to generate. The callback function is
        % expected to take exactly one argument: An object of type ProblemData.
        CallbackFunction;
    end
    
    methods (Access = private)
        function garden_updated(app, update_focus)
            arguments
                app (1, 1)
                update_focus (1, 1) logical = true;
            end
            % Add a progress bar so the user doesn't click everywhere out of
            % impatience.
            loadbar = uiprogressdlg(app.ProblemDataerzeugenUIFigure, ...
                'Title', "Bitte warten", ...
                'Message', "Garten wird aktualisiert...");
            
            % Make a plot
            % Clear any previous clutter
            delete(app.GartenPlotTab.Children);
            ax = axes(app.GartenPlotTab);
            % Make the plot and a custom colorbar
            app.Garten.plot(ax);
            
            % Change active tab to garden view.
            if (update_focus)
                app.TabGroup.SelectedTab = app.GartenPlotTab;
            end
            
            % Update loading bar
            loadbar.Value = 0.5;
            
            %% ---------- GENERATE THE TABLE ----------
            app.UITable.Data = app.Garten.Raw;
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnName = string(1:size(app.Garten, 2));
            app.UITable.ColumnWidth = repmat({30}, 1, size(app.Garten, 2));
            app.UITable.ColumnEditable = true;
            app.UITable.RearrangeableColumns = false;
            app.UITable.RowName = string(1:size(app.Garten, 1));
            app.UITable.CellEditCallback = @(uitable, event) table_updated(app, uitable);
            s = uistyle('HorizontalAlignment', 'center');
            addStyle(app.UITable, s);
            
            % Update loading bar
            loadbar.Value = 1.0;
            loadbar.Message = "Aktualisiere das UI";
            drawnow();
            close(loadbar);
            
            function table_updated(app, table)
                app.Garten = table.Data;
                app.garden_updated(false);
            end
        end
        
        function pdata_updated(app)
            %% Update UI components upon a change in ProblemData.
            %
            %   If the ProblemData object that this app manages is changed in
            %   any way, this function shall be called to ensure that all UI
            %   components reflect the current state of the ProblemData object.
            %   It is called e.g. upon the user importing an object of type
            %   ProblemData.
            [Kompost_Col, Kompost_Row] = ind2sub(size(app.PData.Garden'), app.PData.Target);
            app.ZSpinner_2.Value = Kompost_Row;
            app.SSpinner_2.Value = Kompost_Col;
            
            [Start_Col, Start_Row] = ind2sub(size(app.PData.Garden'), app.PData.Start);
            app.ZSpinner.Value = Start_Row;
            app.SSpinner.Value = Start_Col;
            
            % Populate all other fields in ProblemData.
            for pmidx = 1:numel(app.PDataMembers)
                cur_mem = app.PDataMembers(pmidx);
                cur_mem.handle.Value = app.PData.(cur_mem.name);
            end
            
            % Update garden
            app.Garten = app.PData.Garden;
        end
        
        function populate_PDataMembers(app)
            %% Add content to the PDataMembers variable.
            %
            % Fills in content to the PDataMembers variable of this app. This
            % variable is used to be able to pseudo-dynamically extend the
            % functionality of the GUI. Due to the way the ProblemData object is
            % encoded, the Start and Target members must be handled explicitly.
            % Thus, they are excluded from the following data structure.
            
            % This is to read out the default values.
            if isempty(app.PData)
                dummy_pdata = lhp.ProblemData(10, 10);
            else
                % This ensures that we read the default values from the PData
                % instance that is passed in at starting time.
                dummy_pdata = app.PData;
            end
            numeric_uieditfield = @(fig, varargin) (uieditfield(fig, 'numeric', varargin{:}));
            
            app.PDataMembers = [...
                make_member_struct(...
                    "Max_Val", ...
                    "Maximale Laubmenge pro Feld", ...
                    numeric_uieditfield, ...
                    dummy_pdata.Max_Val, ...
                    [1, 1000]), ...
                make_member_struct(...
                    "Max_Trans", ...
                    "Maximale Transportmenge", ...
                    numeric_uieditfield, ...
                    dummy_pdata.Max_Trans, ...
                    [1, 1000]), ...
                make_member_struct(...
                    "Rake_Param", ...
                    "Aufwandsfaktor Harken", ...
                    numeric_uieditfield, ...
                    dummy_pdata.Rake_Param, ...
                    [0, 10]), ...
                make_member_struct(...
                    "Unprod_Param", ...
                    "Aufwandsfaktor unprod. Wege", ...
                    numeric_uieditfield, ...
                    dummy_pdata.Unprod_Param, ...
                    [0, 10]), ...
                make_member_struct(...
                    "Trans_Param", ...
                    "Aufwandsfaktor Transport", ...
                    numeric_uieditfield, ...
                    dummy_pdata.Trans_Param, ...
                    [0, 10]), ...
                make_member_struct(...
                    "DiagonalWeight", ...
                    "Diagonale Weglänge", ...
                    numeric_uieditfield, ...
                    dummy_pdata.DiagonalWeight, ...
                    [0, Inf])
                ];
            
            function mem_str = make_member_struct(name, description, ui_type, default_value, limits, handle)
                if nargin < 6
                    handle = [];
                end
                mem_str = struct(...
                    "name", name, ...
                    "description", description, ...
                    "ui_type", ui_type, ...
                    "value", default_value, ...
                    "limits", limits, ...
                    "handle", handle);
            end
        end
        
        function inflate_pdata(app)
%             panel_height = app.ProblemDataBeschreibungPanel.Position(4);
            panel_height = app.KompostLabel.Position(2);
            % Explicitly make Start and Compost values here.
            
            % Automatically generate everything else.
            cur_height = panel_height - 40;
            LABEL_HEIGHT = 24;
            LABEL_WIDTH = 180;
            ROW_SPACING = 6;
            for midx = 1:numel(app.PDataMembers)
                member = app.PDataMembers(midx);
                
                uilabel(app.ProblemDataBeschreibungPanel, ...
                    "Text", member.description, ...
                    "Position", [14, cur_height, LABEL_WIDTH, LABEL_HEIGHT], ...
                    "HorizontalAlignment", 'right');
                
                h = member.ui_type(app.ProblemDataBeschreibungPanel, ...
                    "Value", member.value, ...
                    "Limits", member.limits, ...
                    "LowerLimitInclusive", 'on', ...
                    "UpperLimitInclusive", 'on', ...
                    "Position", [14 + LABEL_WIDTH + 6, cur_height, 44, LABEL_HEIGHT], ...
                    "ValueChangedFcn", @(ute, event) (update_member(app, midx, ute.Value)));
                member.handle = h;
                
                cur_height = cur_height - (LABEL_HEIGHT + ROW_SPACING);
            end
                
            function update_member(app, midx, new_value)
                % We must make sure that the configured maximum amount of leaves
                % per cell doesn't violate the leave amounts already present in
                % the garden. If they do, we need the user to interfere and
                % choose whether we should "trim" the invalid garden fields or
                % not.
                if (app.PDataMembers(midx).name == "Max_Val")
                    if any(app.Garten > new_value, 'all')
                        message = sprintf("Eingestellte Laubmenge (%d) liegt unter" + ...
                           " der Laubmenge einiger Gartenfelder! Soll der" + ...
                           " Garten angepasst werden?", new_value);
                        title = "Laubmenge ungueltig";
                        choice = uiconfirm(app.ProblemDataerzeugenUIFigure, ...
                            message, title, 'Icon', 'warning');
                        % Evaluate
                        switch (choice)
                            case "OK"
                                % As we flipped the indexing, we must work on
                                % the raw garden values here!
                                app.Garten.Raw(app.Garten.Raw() > new_value) = new_value;
                                app.garden_updated(false);
                                % Continue below
                            case "Cancel"
                                % Abort
                                return;
                            otherwise
                                error("Case '%s' not implemented!", choice);
                        end
                    end
                end
                
                app.PDataMembers(midx).value = new_value;
                if ~isempty(app.PData)
                    % DiagonalWeight can only be set during creation!
                    if (app.PDataMembers(midx).name == "DiagonalWeight")
                        return;
                    end
                    
                    app.PData.(app.PDataMembers(midx).name) = new_value;
                end
            end
        end
        
        function import_from_workspace(app, user_var)
            %% Import garden or ProblemData from workspace
            %
            %   Given a string for a variable, this method attempts to import
            %   the variable from the Matlab base workspace.
            %
            %   Parameters
            %   ----------
            %   user_var: string
            %       The name of a variable that exists in the base workspace.
            %       The variable can be either of type ProblemData, or it is a
            %       garden matrix.
            base_vars = evalin('base', 'who');
            exists = cellfun(@(x) strcmp(x, user_var), base_vars);
            
            if ~any(exists)
                uialert(app.ProblemDataerzeugenUIFigure, ...
                    sprintf("Variable %s existiert nicht im base workspace!", user_var), ...
                    "Variable nicht gefunden", ...
                    'Icon', 'error');
                return;
            end
            % user_var is valid!
            var = evalin('base', user_var);
            if isa(var, 'lhp.ProblemData')
                % Make sure we have all metadata.
                var = var.unflatten();
                app.PData = var;
                
                app.pdata_updated();
                app.garden_updated();
            else
                % Check if it's a matrix
                if isscalar(var)
                    uialert(app.ProblemDataerzeugenUIFigure, ...
                        sprintf("Variable %s ist kein Garten, weil sie skalar ist!", user_var), ...
                        "Garten nicht gefunden", ...
                        'Icon', 'error');
                    return;
                end
                if ~isa(var, 'lhp.Garden')
                    uialert(app.ProblemDataerzeugenUIFigure, ...
                        sprintf("Variable %s hat keinen Garten-Datentyp!", user_var), ...
                        "Garten nicht gefunden", ...
                        'Icon', 'error');
                    return;
                end
                % It's a Garden-object. Import it.
                app.Garten = var;
                
                app.garden_updated();
            end
        end
        
        function import_from_file(app, filename)
            %% Import data from a file.
            %
            %   Attempts to read a given file and import useful data from it.
            %   What is read in depends on the type of file that is chosen.
            %   Valid choices include:
            %
            %   - '.xls', '.xlsx': Attempt to read a garden from the given Excel
            %     Spreadsheet
            %   - '.csv': Attempt to read in a garden in CSV format
            %   - '.mat': Depending on the content: If one or more instances of
            %     ProblemData are inside, the first one is read in. If one or
            %     more garden matrices are inside, the first one of those is
            %     read instead. ProblemData is always preferred if both exist.
            %
            %   Parameters
            %   ----------
            %   filename: string
            %       A filename (with path if not in the Matlab path) to one of
            %       the supported files to be read. See above for supported file
            %       formats and what is read in.
            arguments
                app (1, 1)
                filename (1, 1) string
            end
            
            % Make sure it's a non-binary file
            if ~isfile(filename)
                uialert(app.ProblemDataerzeugenUIFigure, ...
                    "Datei '%s' existiert nicht!", "Datei nicht gefunden");
                return;
            end
            
            [~, ~, extension] = fileparts(filename);
            try
                switch (extension)
                    case {'.xls', '.xlsx'}
                        % Read in a garden matrix.
                        app.Garten = lhp.Garden(readmatrix(filename));
                        app.garden_updated();
                    case {'.csv'}
                        % Read in a garden matrix.
                        app.Garten = lhp.Garden(readmatrix(filename));
                        app.garden_updated();
                    case {'.mat'}
                        mf = matfile(filename);
                        mf_vars = whos(mf);
                        
                        pdatas = strcmp(arrayfun(@(struct) string(struct.class), mf_vars), "lhp.ProblemData");
                        if any(pdatas)
                            % Found object of type ProblemData
                            var_index = find(pdatas > 0, 1);
                            varname = mf_vars(var_index).name;
                            
                            app.PData = mf.(varname);
                            app.pdata_updated();
                            app.garden_updated();
                            return;
                        else
                            % Looking for garden matrix.
                            garden_candidates = find(all(vertcat(mf_vars.size) > 10, 2));
                            for gc_idx = 1:numel(garden_candidates)
                                gc = garden_candidates(gc_idx);
                                var = mf.(mf_vars(gc).name);
                                if isa(var, 'lhp.Garden')
                                    app.Garten = var;
                                    app.garden_updated();
                                    return;
                                end
                            end
                        end
                    otherwise
                        uialert(app.ProblemDataerzeugenUIFigure, ...
                            "Dateiendung '%s' wird nicht unterstuetzt!", "Datei ungueltig");
                        return;
                end
            catch ME
                uialert(app.ProblemDataerzeugenUIFigure, ...
                    "Fehler", ME.message);
                return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kvargs)
            %% App invocation function
            %
            %   This method is executed when the App starts. It sets up some
            %   essential internal data structures.
            %
            %   Key-Value Parameters
            %   --------------------
            %   'Callback': function_handle, default: @disp
            %       A function handle to a callback function. The callback is
            %       executed when the used generates a ProblemData object. The
            %       callback must take exactly one argument of type ProblemData.
            %       If none is provided, the generated object is printed to
            %       stdout.
            %   'ProblemData': ProblemData
            %       An instance of ProblemData that is to be changed through the
            %       UI. If left empty, the user will create an instance from
            %       scratch. If provided, the values from the given instance
            %       will be used as defaults.
            arguments
                app (1, 1)
                kvargs.Callback (1, 1) function_handle = @disp;
                kvargs.ProblemData (1, 1) lhp.ProblemData
            end
            
            if isfield(kvargs, "ProblemData")
                app.PData = kvargs.ProblemData;
                app.pdata_updated();
                app.garden_updated();
            end
            
            app.populate_PDataMembers();
            app.inflate_pdata();
            
            app.CallbackFunction = kvargs.Callback;
        end

        % Button pushed function: GartenErzeugenButton
        function GartenErzeugenButtonPushed(app, event)
            %% Callback when garden is executed.
            %
            %   This callback is executed when the used chooses to generate a
            %   garden randomly.
            app.Garten = lhp.Garden.random(...
                app.ZeilenSpinner.Value, ...
                app.SpaltenSpinner.Value, ...
                "Export", false, ...
                "WithCompost", logical(app.MitKompostCheckBox.Value));
            
            app.garden_updated();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ProblemDataerzeugenUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {483, 483};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ImportierenButton_2
        function ImportierenButton_2Pushed(app, event)
            user_var = string(app.DateinameasdEditField_2.Value);
            app.import_from_workspace(user_var);
        end

        % Button pushed function: ProblemErzeugenButton
        function ProblemErzeugenButtonPushed(app, event)
            if isempty(app.Garten)
                uialert(app.ProblemDataerzeugenUIFigure, ...
                    "Es wurde noch kein Garten ausgewaehlt!", "Fehler");
                return;
            end
            
            Start = [app.ZSpinner.Value, app.SSpinner.Value];
            Kompost = [app.ZSpinner_2.Value, app.SSpinner_2.Value];
            DiagonalWeight = app.PDataMembers(...
                [app.PDataMembers.name] == "DiagonalWeight").value;
            
            % Sanity checks.
            if nnz(app.Garten == (-10)) > 1
                uialert(app.ProblemDataerzeugenUIFigure, ...
                    "Garten darf maximal eine Kompoststelle (-10) haben!", "Fehler");
                return;
            elseif (nnz(app.Garten == (-10)) == 1) && any(Kompost ~= 0)
                if nnz(Kompost == 0) == 1
                    uialert(app.ProblemDataerzeugenUIFigure, ...
                        "Kompost Indizes muessen entweder [0, 0] oder beide von 0 verschieden sein!", ...
                        "Ungueltige Indizes");
                    return;
                end
                % Maybe they are at the same position?
                if (app.Garten(Kompost(1), Kompost(2)) ~= -10)
                    uialert(app.ProblemDataerzeugenUIFigure, ...
                        "Garten hat bereits eine Kompoststelle, die sich von der selbst" + ...
                        " definierten Stelle unterscheidet!", ...
                        "Fehler beim Kompost");
                    return;
                end
            end

            % Create ProblemData now.
            while 1
                try
                    app.PData = lhp.ProblemData(30, 30, ...
                        "Garten", app.Garten, ...
                        "Start", Start, ...
                        "Kompost", Kompost, ...
                        "DiagonalWeight", DiagonalWeight);
                    break;
                catch ME
                    uiconfirm(app.ProblemDataerzeugenUIFigure, ...
                        ME.message, "Fehler", ...
                        'Icon', 'error', ...
                        'Options', {'OK'});
%                         sprintf("In: %s \nArgument: %s \nError message: %s", ...
%                             ME.FunctionName, ME.ArgumentName, ME.message), ...
%                         ME.identifier);
                    return;
                end
            end
            
            % Start and Kompost may have been generated by random. In this case,
            % we must update the values in the GUI.
%             [Kompost_Col, Kompost_Row] = ind2sub(size(app.PData.Garden'), app.PData.Target);
%             app.ZSpinner_2.Value = Kompost_Row;
%             app.SSpinner_2.Value = Kompost_Col;
            [app.ZSpinner_2.Value, app.SSpinner_2.Value] = app.PData.Garden.ind2sub(app.PData.Target);
            
            [Start_Col, Start_Row] = ind2sub(size(app.PData.Garden'), app.PData.Start);
            app.ZSpinner.Value = Start_Row;
            app.SSpinner.Value = Start_Col;
            
            % Populate all other fields in ProblemData.
            for pmidx = 1:numel(app.PDataMembers)
                cur_mem = app.PDataMembers(pmidx);
                
                % Must skip DiagonalWeight, it is read-only after creation!
                if (cur_mem.name == "DiagonalWeight")
                    continue;
                end
                
                app.PData.(cur_mem.name) = cur_mem.value;
            end

            % Update garden, as compost may have moved
            app.Garten = app.PData.Garden;
            app.garden_updated();
            
            app.CallbackFunction(app.PData);
        end

        % Button pushed function: ImportierenButton
        function ImportierenButtonPushed(app, event)
            filename = string(app.DateinameasdEditField.Value);
            app.import_from_file(filename);
        end

        % Button pushed function: ImDateimanagerwhlenButton
        function ImDateimanagerwhlenButtonPushed(app, event)
            [file, path] = uigetfile(...
                {'*.xls;*.xlsx', 'Excel Dateien (*.xls, *.xlsx)'; ...
                 '*.csv', 'CSV-Dateien (*.csv)'; ...
                 '*.mat', 'MAT-Dateien (*.mat)'}, ...
                 "Garten- oder ProblemData-Datei auswaehlen");
             filepath = string(path) + string(file);
             app.import_from_file(filepath);
        end

        % Menu selected function: AuseinerDateiMenu
        function AuseinerDateiMenuSelected(app, event)
            app.ImDateimanagerwhlenButtonPushed(event);
        end

        % Menu selected function: AusdemWorkspaceMenu
        function AusdemWorkspaceMenuSelected(app, event)
            varname = inputdlg(...
                "Name der Variablen im Base Workspace:", ...
                "Variable auswaehlen", ...
                [1, 40]);
            app.import_from_workspace(string(varname));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProblemDataerzeugenUIFigure = uifigure('Visible', 'off');
            app.ProblemDataerzeugenUIFigure.Name = 'ProblemData erzeugen';
            app.ProblemDataerzeugenUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ProblemDataerzeugenUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.ProblemDataBeschreibungPanel = uipanel(app.GridLayout4);
            app.ProblemDataBeschreibungPanel.Title = 'ProblemData Beschreibung';
            app.SSpinnerLabel = uilabel(app.ProblemDataBeschreibungPanel);
            app.SSpinnerLabel.Tooltip = {'Spalte'};
            app.SSpinnerLabel.Text = 'S';
            app.SSpinner = uispinner(app.ProblemDataBeschreibungPanel);
            app.SSpinner.Limits = [0 Inf];
            app.SSpinner.ValueDisplayFormat = '%.0f';
            app.SSpinner.Tooltip = {'Spalte'};
            app.SSpinner_2Label = uilabel(app.ProblemDataBeschreibungPanel);
            app.SSpinner_2Label.Tooltip = {'Spalte'};
            app.SSpinner_2Label.Text = 'S';
            app.SSpinner_2 = uispinner(app.ProblemDataBeschreibungPanel);
            app.SSpinner_2.Limits = [0 Inf];
            app.SSpinner_2.ValueDisplayFormat = '%.0f';
            app.SSpinner_2.Tooltip = {'Spalte'};
            app.ZSpinnerLabel = uilabel(app.ProblemDataBeschreibungPanel);
            app.ZSpinnerLabel.Tooltip = {'Spalte'};
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.ProblemDataBeschreibungPanel);
            app.ZSpinner.Limits = [0 Inf];
            app.ZSpinner.ValueDisplayFormat = '%.0f';
            app.ZSpinner.Tooltip = {'Zeile'};
            app.ZSpinner_2Label = uilabel(app.ProblemDataBeschreibungPanel);
            app.ZSpinner_2Label.Tooltip = {'Spalte'};
            app.ZSpinner_2Label.Text = 'Z';
            app.ZSpinner_2 = uispinner(app.ProblemDataBeschreibungPanel);
            app.ZSpinner_2.Limits = [0 Inf];
            app.ZSpinner_2.ValueDisplayFormat = '%.0f';
            app.ZSpinner_2.Tooltip = {'Zeile'};
            app.StartLabel = uilabel(app.ProblemDataBeschreibungPanel);
            app.StartLabel.Text = 'Start';
            app.KompostLabel = uilabel(app.ProblemDataBeschreibungPanel);
            app.KompostLabel.Text = 'Kompost';
            app.ProblemErzeugenButton = uibutton(app.ProblemDataBeschreibungPanel, 'push');
            app.ProblemErzeugenButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemErzeugenButtonPushed, true);
            app.ProblemErzeugenButton.Tooltip = {'Erzeugt das konfigurierte Objekt vom Typ ProblemData.'};
            app.ProblemErzeugenButton.Text = 'Problem Erzeugen';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GartenerzeugenTab = uitab(app.TabGroup);
            app.GartenerzeugenTab.Title = 'Garten erzeugen';
            app.GridLayout3 = uigridlayout(app.GartenerzeugenTab);
            app.ImportausDateiPanel = uipanel(app.GridLayout3);
            app.ImportausDateiPanel.Tooltip = {'Wählt eine ".mat" Datei aus, aus der die Daten importiert werden. Importiert werden können Matrizen (als Garten) und Objekte vom Typ ProblemData (hier wird dann der Garten aus dem Attribut "Garden" entnommen). Sollte in einer ".mat" Datei beides enthalten sein, wird das ProblemData-Objekt bevorzugt.'};
            app.ImportausDateiPanel.Title = 'Import aus Datei';
            app.DateinameasdEditField = uieditfield(app.ImportausDateiPanel, 'text');
            app.DateinameLabel = uilabel(app.ImportausDateiPanel);
            app.DateinameLabel.Text = 'Dateiname:';
            app.ImDateimanagerwhlenButton = uibutton(app.ImportausDateiPanel, 'push');
            app.ImDateimanagerwhlenButton.ButtonPushedFcn = createCallbackFcn(app, @ImDateimanagerwhlenButtonPushed, true);
            app.ImDateimanagerwhlenButton.Text = 'Im Dateimanager wählen';
            app.ImportierenButton = uibutton(app.ImportausDateiPanel, 'push');
            app.ImportierenButton.ButtonPushedFcn = createCallbackFcn(app, @ImportierenButtonPushed, true);
            app.ImportierenButton.Text = 'Importieren';
            app.ImportausWorkspacePanel = uipanel(app.GridLayout3);
            app.ImportausWorkspacePanel.Tooltip = {'Importiert eine Variable aus dem Workspace in das Programm. Wenn die ausgewählte Variable eine numerische Matrix ist, wird sie als Garten eingelesen. Ist die Variable ein Objekt vom Typ ProblemData, wird das ganze Objekt eingelesen.'};
            app.ImportausWorkspacePanel.Title = 'Import aus Workspace';
            app.VariablennameLabel = uilabel(app.ImportausWorkspacePanel);
            app.VariablennameLabel.Text = 'Variablenname:';
            app.DateinameasdEditField_2 = uieditfield(app.ImportausWorkspacePanel, 'text');
            app.ImportierenButton_2 = uibutton(app.ImportausWorkspacePanel, 'push');
            app.ImportierenButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportierenButton_2Pushed, true);
            app.ImportierenButton_2.Text = 'Importieren';
            app.RandomisierterzeugenPanel = uipanel(app.GridLayout3);
            app.RandomisierterzeugenPanel.Tooltip = {'Erzeugt einen randomisierten Garten mit der Funktion "zufaelligerGarten".'};
            app.RandomisierterzeugenPanel.Title = 'Randomisiert erzeugen';
            app.ZeilenSpinnerLabel = uilabel(app.RandomisierterzeugenPanel);
            app.ZeilenSpinnerLabel.Text = 'Zeilen';
            app.ZeilenSpinner = uispinner(app.RandomisierterzeugenPanel);
            app.ZeilenSpinner.Limits = [10 1000];
            app.ZeilenSpinner.ValueDisplayFormat = '%.0f';
            app.ZeilenSpinner.Tooltip = {'Anzahl Zeilen der Gartenmatrix.'};
            app.ZeilenSpinner.Value = 10;
            app.SpaltenSpinnerLabel = uilabel(app.RandomisierterzeugenPanel);
            app.SpaltenSpinnerLabel.Text = 'Spalten';
            app.SpaltenSpinner = uispinner(app.RandomisierterzeugenPanel);
            app.SpaltenSpinner.Limits = [10 1000];
            app.SpaltenSpinner.ValueDisplayFormat = '%.0f';
            app.SpaltenSpinner.Tooltip = {'Anzahl Spalten des Gartens.'};
            app.SpaltenSpinner.Value = 10;
            app.MitKompostCheckBox = uicheckbox(app.RandomisierterzeugenPanel);
            app.MitKompostCheckBox.Tooltip = {'Wählt aus, ob bereits (randomisiert) ein Kompost im Garten angelegt werden soll. Wenn diese Option abgewählt wird, muss später ein Kompostfeld bestimmt werden.'};
            app.MitKompostCheckBox.Text = 'Mit Kompost';
            app.MitKompostCheckBox.Value = true;
            app.GartenErzeugenButton = uibutton(app.RandomisierterzeugenPanel, 'push');
            app.GartenErzeugenButton.ButtonPushedFcn = createCallbackFcn(app, @GartenErzeugenButtonPushed, true);
            app.GartenErzeugenButton.Text = 'Garten Erzeugen';
            app.GartenPlotTab = uitab(app.TabGroup);
            app.GartenPlotTab.Title = 'Garten-Plot';
            app.ManuellanpassenTab = uitab(app.TabGroup);
            app.ManuellanpassenTab.Title = 'Manuell anpassen';
            app.UITable = uitable(app.ManuellanpassenTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.DateiMenu = uimenu(app.ProblemDataerzeugenUIFigure);
            app.DateiMenu.Text = 'Datei';
            app.ImportMenu = uimenu(app.DateiMenu);
            app.ImportMenu.Text = 'Import';
            app.AusdemWorkspaceMenu = uimenu(app.ImportMenu);
            app.AusdemWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @AusdemWorkspaceMenuSelected, true);
            app.AusdemWorkspaceMenu.Text = 'Aus dem Workspace';
            app.AuseinerDateiMenu = uimenu(app.ImportMenu);
            app.AuseinerDateiMenu.MenuSelectedFcn = createCallbackFcn(app, @AuseinerDateiMenuSelected, true);
            app.AuseinerDateiMenu.Text = 'Aus einer Datei';
            app.ExportMenu = uimenu(app.DateiMenu);
            app.ExportMenu.Text = 'Export';
            app.IndenWorkspaceMenu = uimenu(app.ExportMenu);
            app.IndenWorkspaceMenu.Text = 'In den Workspace';
            app.IneineDateiMenu = uimenu(app.ExportMenu);
            app.IneineDateiMenu.Text = 'In eine Datei';
            % Show the figure after all components are created
            app.ProblemDataerzeugenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProblemData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProblemDataerzeugenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProblemDataerzeugenUIFigure)
        end
    end
end