classdef TestManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaubharkproblemUIFigure       matlab.ui.Figure
        DateiMenu                     matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        AusdemWorkspaceMenu           matlab.ui.container.Menu
        AuseinerDateiMenu             matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        IndenWorkspaceMenu            matlab.ui.container.Menu
        IneineDateiMenu               matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        TestvorbereitungTab           matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        Tree2                         matlab.ui.container.Tree
        DeterministischeHeuristikenNode  matlab.ui.container.TreeNode
        StochastischeHeuristikenNode  matlab.ui.container.TreeNode
        AlgorithmenLabel              matlab.ui.control.Label
        ProblemDataLabel              matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ListBox                       matlab.ui.control.ListBox
        Panel                         matlab.ui.container.Panel
        NeuButton                     matlab.ui.control.Button
        ndernButton                   matlab.ui.control.Button
        LschenButton                  matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        NeuButton_2                   matlab.ui.control.Button
        ndernButton_2                 matlab.ui.control.Button
        LschenButton_2                matlab.ui.control.Button
        Tree3                         matlab.ui.container.Tree
        TestsdurchfhrenButton         matlab.ui.control.Button
        TestManagerTab                matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        EinstellungenPanel            matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        Tree                          matlab.ui.container.Tree
        ZusammenfassenderDatenButtonGroup  matlab.ui.container.ButtonGroup
        MittelwertButton              matlab.ui.control.ToggleButton
        MedianButton                  matlab.ui.control.ToggleButton
        MinimumButton                 matlab.ui.control.ToggleButton
        MaximumButton_2               matlab.ui.control.ToggleButton
        Panel_3                       matlab.ui.container.Panel
        DarstellungDropDownLabel      matlab.ui.control.Label
        DarstellungDropDown           matlab.ui.control.DropDown
        Panel_5                       matlab.ui.container.Panel
        ZuzeigendeDatenButtonGroup    matlab.ui.container.ButtonGroup
        GesamtkostenButton            matlab.ui.control.ToggleButton
        HarkkostenButton              matlab.ui.control.ToggleButton
        WegekostenButton              matlab.ui.control.ToggleButton
        TransportkostenButton         matlab.ui.control.ToggleButton
        AusfhrungszeitsButton_2       matlab.ui.control.ToggleButton
        TestauswahlPanel              matlab.ui.container.Panel
        TestIDDropDownLabel           matlab.ui.control.Label
        TestIDDropDown                matlab.ui.control.DropDown
        TabGroup2                     matlab.ui.container.TabGroup
        bersichtTab                   matlab.ui.container.Tab
        bersichtPanel                 matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        ProTestTab                    matlab.ui.container.Tab
        Panel_6                       matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        UITable2                      matlab.ui.control.Table
        UIAxes2                       matlab.ui.control.UIAxes
        ErgebnisseTab                 matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        GartenPlotTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        tm; % Instanz des TestManager, den dieses GUI verwaltet
        tm_tbl; % Ergebnistabelle des TestManager
        loadbar; % Ladebalken fuer Ergebnistab.
    end
    
    properties (Access=private, SetObservable)
        tm_cur_tbl; % Ergebnistabelle nach Anwendung der Filterkriterien
        algorithms; % Algorithms defined by the user
        problems; % Problems defined by the user
    end
    
    methods (Access = private)
        
        function updateTable(app)
            switch app.DarstellungDropDown.Value
                case "Übersicht"
                    app.UITable.Data = app.tm_cur_tbl;
                    app.UITable.ColumnEditable = false;
                    app.UITable.RowName = app.tm_cur_tbl.TestCaseID;
                    app.UITable.ColumnName = app.tm_cur_tbl.Properties.VariableNames;
                    % Highlight all cells with minimum values per row
                    if ~isempty(app.tm_cur_tbl)
                        removeStyle(app.UITable);
                        s = uistyle("BackgroundColor", [0.6, 1.0, 0.6]);
                        [vals, min_idx] = min(app.tm_cur_tbl{:, 4:end}, [], 2);
                        % Remember we ignore the first 3 cols
                        min_idx = min_idx + 3;
                        rows = (1:size(app.tm_cur_tbl, 1))';
                        not_nan = ~isnan(vals);
                        if any(not_nan)
                            addStyle(app.UITable, s, 'cell', [rows(not_nan), min_idx(not_nan)]);
                        end
                    end
                    
                case "Pro Test"
                    app.UITable2.Data = app.tm_cur_tbl;
                    app.UITable2.ColumnEditable = false;
                    app.UITable2.RowName = app.tm_cur_tbl.Properties.RowNames;
                    app.UITable2.ColumnName = app.tm_cur_tbl.Properties.VariableNames;
                    
                    % Highlight all cells with minimum values per row
                    if ~isempty(app.tm_cur_tbl)
                        removeStyle(app.UITable2);
                        s = uistyle("BackgroundColor", [0.6, 1.0, 0.6]);
                        [vals, min_idx] = min(app.tm_cur_tbl{:, :}, [], 1);
                        cols = (1:size(app.tm_cur_tbl, 2));
                        not_nan = ~isnan(vals);
                        if any(not_nan)
                            addStyle(app.UITable2, s, 'cell', [min_idx(not_nan); cols(not_nan)]');
                        end
                    end
                    
                    % Add the bar plot, too
                    bar_data = [ ...
                        app.tm_cur_tbl.Harkarbeit, ...
                        app.tm_cur_tbl.Wegearbeit, ...
                        app.tm_cur_tbl.Transportarbeit, ...
                        ];
                    x = categorical(app.tm_cur_tbl.Properties.RowNames);
                    x = reordercats(x, app.tm_cur_tbl.Properties.RowNames);
                    
                    % Clear the XAxis because categorical XAxis seem to persist
                    % across replots. This means that MATLAB would never update
                    % the order of the X-axis categoricals, which is an error,
                    % as we change their sorting.
                    % No idea who thought that was a good idea, it's just
                    % unexpected and not documented as well as it should be.
                    % See here: https://www.mathworks.com/help/matlab/ref/matlab.graphics.axis.decorator.categoricalruler-properties.html
                    reset(app.UIAxes2.XAxis);
                    b = bar(app.UIAxes2, x, bar_data, 'stacked');
                    legend(app.UIAxes2, ["Harken", "Wege", "Transport"], ...
                        'Location', 'southeast');
                    % Add data tips to the bars
                    xtips = b.XEndPoints;
                    ytips = b.YEndPoints;
                    labels = string(app.tm_cur_tbl.Gesamtarbeit);
                    text(app.UIAxes, xtips, ytips, labels, ...
                        "HorizontalAlignment", "right", ...
                        "VerticalAlignment", "middle", ...
                        "Rotation", 90);
                    
                    ylabel(app.UIAxes2, "Aufwand");
                    xlabel(app.UIAxes2, "Algorithmus");
                    
                otherwise
                    error("Unbekannte Darstellungsart '%s'", ...
                        app.DarstellungDropDown.Value);
            end
        end
        
        function updateErgebnisSelection(app)
            % To tell if we opened a progressbar or not.
            prog_bar = false;
            % Unless some progressbar is already open, we create our own to keep
            % the user busy while waiting for the results.
            if isempty(app.loadbar) || ~isvalid(app.loadbar)
                app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                    'Title', 'Bereite Daten auf...', ...
                    'Indeterminate', "on");
                prog_bar = true;
            end
            
            switch app.DarstellungDropDown.Value
                case "Übersicht"
                    % Get the variable to select
                    var_btn = app.ZuzeigendeDatenButtonGroup.SelectedObject;
                    switch (var_btn.Text)
                        case "Gesamtkosten"
                            var = "Gesamtkosten";
                        case "Harkkosten"
                            var = "Harkkosten";
                        case "Wegekosten"
                            var = "Wegekosten";
                        case "Transportkosten"
                            var = "Transportkosten";
                        case "Ausführungszeit (s)"
                            var = "Zeit";
                        otherwise
%                             var = var_btn.Text;
                            error("Unbekannte Variable '%s'", var_btn.Text);
                    end
                    
                    % Get the operation to perform
                    op_btn = app.ZusammenfassenderDatenButtonGroup.SelectedObject;
                    switch (op_btn.Text)
                        case "Mittelwert"
                            op = "Mean";
                        case "Median"
                            op = "Median";
                        case "Minimum"
                            op = "Minimum";
                        case "Maximum"
                            op = "Maximum";
                        otherwise
%                             op = op_btn.Text;
                            error("Unbekannte Operation '%s'", op_btn.Text);
                    end
                    
                    app.tm_cur_tbl = app.tm.get_results("Value", var, "Merge", op);
                    % Due to the listener we connected previously we don't have to do
                    % anything else after updating the table to plot.
                    
                case "Pro Test"
                    % Get the test we shall display
                    test_id = str2double(app.TestIDDropDown.Value);
                    if isempty(test_id) || isnan(test_id)
                        % There's no test yet, exit here.
                        return;
                    end
                    
                    test_selection = (app.tm_tbl.TestCaseID == test_id);
                    if ~any(test_selection)
                        error("TestID '%d' existiert nicht!", test_id);
                    end
                    
                    % Perform data merging for bio algorithms
                    tmp_tbl = app.tm.get_results("From", test_selection);
                    
                    op_btn = app.ZusammenfassenderDatenButtonGroup.SelectedObject;
                    switch (op_btn.Text)
                        case "Mittelwert"
                            op = @mean;
                        case "Median"
                            op = @median;
                        case "Minimum"
                            op = @min;
                        case "Maximum"
                            op = @max;
                        otherwise
%                             op = op_btn.Text;
                            error("Unbekannte Operation '%s'", op_btn.Text);
                    end
                    
                    subres = tmp_tbl(:, 4:end);
                    selection = varfun(@(cell) (cellfun(...
                        @(struct) (lhp.gui.TestManager.get_data(struct, op)), cell)), subres);
                    tmp_tbl = [tmp_tbl(:, 1:3), selection];
                    
                    % Perform some magic and format the data table properly
                    num_algs = size(tmp_tbl, 2) - 3;
                    col_names = {'Harkarbeit', 'Transportarbeit', 'Wegearbeit', ...
                        'Gesamtarbeit', 'Kosten %', 'Zeit', 'Rang'};
                    tb = table('Size', [num_algs, numel(col_names)], ...
                        'VariableTypes', {'double', 'double', 'double', 'double', 'double', 'double', 'uint32'}, ...
                        'RowNames', subres.Properties.VariableNames, ...
                        'VariableNames', col_names);
                    % Fill in the data
                    tb.Harkarbeit = arrayfun(@(struct) struct.HA, tmp_tbl{1, 4:end})';
                    tb.Transportarbeit = arrayfun(@(struct) struct.TA, tmp_tbl{1, 4:end})';
                    tb.Wegearbeit = arrayfun(@(struct) struct.WA, tmp_tbl{1, 4:end})';
                    tb.Gesamtarbeit = arrayfun(@(struct) struct.K, tmp_tbl{1, 4:end})';
                    tb.Zeit = arrayfun(@(struct) struct.time, tmp_tbl{1, 4:end})';
                    tb.("Kosten %") = 100 * tb.Gesamtarbeit ./ min(tb.Gesamtarbeit);
                    [~, ~, tb.Rang] = unique(tb.Gesamtarbeit);
                    
                    app.tm_cur_tbl = sortrows(tb, 'Gesamtarbeit', 'ascend');
                    % Due to the listener we connected previously we don't have to do
                    % anything else after updating the table to plot.
                    
                otherwise
                    delete(app.loadbar);
                    error("Unbekannte Darstellungsart '%s'", ...
                        app.DarstellungDropDown.Value);
            end
            
            if prog_bar
                delete(app.loadbar);
            end
        end
        
        function updateAlgorithms(app)
            % Update the algorithms view in the first GUI tab
            %
            %   Triggered when the apps internal "algorithm" member variable
            %   changes, e.g. because a new algorithm is added or an existing
            %   one is removed.
            
            % Clear the tree view first
            delete(app.DeterministischeHeuristikenNode.Children);
            delete(app.StochastischeHeuristikenNode.Children);
            
            tm_algorithms = app.tm.get_algorithms();
            for alg_idx = 1:numel(tm_algorithms)
                alg = tm_algorithms{alg_idx};
                if isa(alg, "lhp.algo.DeterministicWrapper")
                    uitreenode(app.DeterministischeHeuristikenNode, ...
                        "Text", alg.get_name("Latex"), ...
                        "NodeData", alg, ...
                        "UserData", alg_idx);
                elseif isa(alg, "lhp.algo.StochasticWrapper")
                    uitreenode(app.StochastischeHeuristikenNode, ...
                        "Text", alg.get_name("Latex"), ...
                        "NodeData", alg, ...
                        "UserData", alg_idx);
                else
                    uialert(app.LaubharkproblemUIFigure, ...
                        sprintf("Found an algorithm of unknown type '%s'!", class(alg)));
                end
            end
            
            expand(app.Tree2, 'all');
        end
        
        function add_algorithm(app, algorithm, ~)
            % Add or change existing algorithms in the App.
            %
            %   This method is called when the user chooses either to create a
            %   new algorithm, or to change an existing one. It is registered as
            %   a callback with the 'lhp.gui.Algorithm' GUI and receives the
            %   newly created algorithm.
            %   If checks if an algorithm by the same name already exists.
            %   We use the name as unique identifier in the TestManager, thus
            %   that would be illegal!
            %
            %   Parameters
            %   ----------
            %   algorithm: lhp.algo.DeterministicWrapper
            %       An instance of LHT_BaseData (or any child class) that is to
            %       be added or changed.
            %   index: positive integer, UNUSED
            %       An unsigned integer number. If provided, the given algorithm
            %       is added to the list of algorithms in the selected position,
            %       i.e. an existing algorithm is overwritten by the new one. If
            %       no index is provided, the algorithm will be appended to the
            %       list.
            
            if nargin > 2
                app.tm.remove_algorithm(algorithm.get_name());
            end
            
            % Check if the name is unique
            tm_algorithms = app.tm.get_algorithms();
            for alg_idx = 1:numel(tm_algorithms)
                alg = tm_algorithms{alg_idx};
                if (strcmp(alg.get_name(), algorithm.get_name()))
                    uialert(app.LaubharkproblemUIFigure, ...
                        sprintf("Es existiert bereits ein Algorithmus mit dem Namen '%s'!", ...
                        algorithm.get_name()), ...
                        "Doppelte Auswahl");
                    return;
                end
            end
            
            % Add algorithm to Testmanager, too
            app.tm.addAlgorithm(algorithm);
            % Update UI
            app.updateTM();
        end
        
        function updateProblems(app)
            %% Update the problems view in the first GUI tab.
            %
            %   Triggered when the apps internal "problems" member variable is
            %   changed by e.g. adding new problems or deleting them. It updates
            %   the text view next to it to reflect the current selection.
            indexes = app.tm_tbl.TestCaseID;
            Items = strcat("TestCase ", string(indexes));
            app.ListBox.Items = Items;
            app.ListBox.ItemsData = indexes;
        end
        
        function add_problem(app, problem, index)
            % Add or change existing problems in the App.
            %
            %   This method is called when the user chooses either to create a
            %   new problem, or to change an existing one. It is registered as
            %   a callback with the 'lhp.gui.ProblemData' GUI and receives the
            %   newly created problem.
            %
            %   Parameters
            %   ----------
            %   problem: lhp.ProblemData
            %       An instance of ProblemData that is to be added or changed.
            %   index: positive integer
            %       An unsigned integer number. If provided, the test case
            %       matching the ID will be removed from the list of tests. The
            %       new one will still be appended.
            
            if nargin > 2
                % Remove the given problem first
                app.tm.remove_test_case(index);
            end
            app.tm.addTestCase(problem);
            % Update all UI views
            app.updateTM();
            % Repopulate the TestID drop down
            app.TestIDDropDown.Items = string(app.tm_tbl.TestCaseID);
        end
        
        function makePdataTree(~, pdata, treeui)
            % Create a tree view from a ProblemData object.
            %
            %   Given an instance of ProblemData, this method inflates the
            %   TreeView in the UI to display the variables so the user can
            %   check which Problem exactly they are currently inspecting.
            %
            %   Parameters
            %   ----------
            %   pdata: lhp.ProblemData
            %       The object to show in the Tree-View
            %   treeui: uitree
            %       A handle to a uitree that is used as parent for the list
            %       view.
            tree_level_idx = 1;
            tree_level_data = struct(...
                'master_node', [], ...
                'fields', {}, ...
                'num_fields', 0, ...
                'parent_var', []);
            
            % Clear the tree in the UI first.
            delete(treeui.Children);
            
            fields = fieldnames(pdata);
            % We keep track of where we are in the tree with these structures.
            % We do a depth-first iteration over all elements of ProblemData,
            % and here we keep track of the tree level on which we are so we can
            % get back to it when we return from the depth search.
            tree_level_data(1).master_node = treeui;
            tree_level_data(1).fields = fields;
            tree_level_data(1).num_fields = numel(fields);
            tree_level_data(1).parent_var = pdata;
            
            while 1
                % All fields have been consumed. Check if we are in some tree
                % level higher than the first. If so: Reduce the tree level,
                % otherwise we have iterated the whole tree.
                if isempty(tree_level_data(tree_level_idx).fields)
                    if tree_level_idx > 1
                        tree_level_data(tree_level_idx) = [];
                        tree_level_idx = tree_level_idx - 1;
                        continue;
                    else
                        return;
                    end
                end
                
                % Pop the frontmost field.
                fieldname = tree_level_data(tree_level_idx).fields{1};
                tree_level_data(tree_level_idx).fields(1) = [];
                tree_level_data(tree_level_idx).num_fields = ...
                    tree_level_data(tree_level_idx).num_fields - 1;
                
                try
                    % This fails on scalar tree elements.
                    tmp = tree_level_data(tree_level_idx).parent_var.(fieldname);
                    new_fields = fieldnames(tmp);
                catch
                    % Field doesn't contain subfields. Add to tree
                    node_data = tree_level_data(tree_level_idx).parent_var.(fieldname);
                    if ~isscalar(node_data)
                        % FIXME: I'm expecting that we don't have 3D data here!
                        node_data = sprintf("%d x %d", size(...
                            node_data));
                    end
                    master_node = tree_level_data(tree_level_idx).master_node;
                    % "Easy" variant: Just display the name in the tree.
                    nodetext = fieldname;
                    % We display the name and some additional data in the tree.
                    if ~isscalar(node_data)
                        % FIXME: I'm expecting that we don't have 3D data here!
                        node_data = sprintf("%d x %d", size(...
                            node_data));
                        nodetext = sprintf("%s: (Size: %s)", fieldname, node_data);
                    elseif isa(node_data, "string")
                        nodetext = sprintf("%s: %s", fieldname, node_data);
                    else
                        nodetext = sprintf("%s: %d", fieldname, node_data);
                    end
                    % Add the node as a slave node of the master of the current
                    % tree level.
                    uitreenode(master_node, 'Text', nodetext, ...
                        'NodeData', node_data);
                    
                    % Field was added to tree. Go to the next.
                    continue;
                end
                
                % The field contains subfields!
                % Increase the tree level
                tree_level_idx = tree_level_idx + 1;
                % Add a new empty structure
                tree_level_data = [tree_level_data, ...  %#ok
                    struct('master_node', [], ...
                        'fields', {}, ...
                        'num_fields', 0, ...
                        'parent_var', [])];

                % Add tree node without data.
                node = uitreenode(tree_level_data(tree_level_idx - 1).master_node, ...
                    'Text', fieldname);
                
                % Register the current node as the master for all nodes that
                % will be spawned below it. Also make sure we record the
                % variable that belongs to the parent node so we can access its
                % children.
                tree_level_data(tree_level_idx).master_node = node;
                tree_level_data(tree_level_idx).fields = new_fields;
                tree_level_data(tree_level_idx).num_fields = numel(new_fields);
                tree_level_data(tree_level_idx).parent_var = ...
                    tree_level_data(tree_level_idx - 1).parent_var.(fieldname);
            end
        end
        
        function plotResults(app, row_index, col_index)
            % Get the Pdata we need and other data, too.
            alg_name = app.tm_tbl.Properties.VariableNames{col_index};
            pdata = app.tm_tbl.ProblemData{row_index};
            result = app.tm_tbl{row_index, col_index}{1};
            
            % Check if we had more than one result. In that case, take the best
            % solution
            if numel(result) > 1
                [~, lowest_cost_idx] = min([result.K]);
                result = result(lowest_cost_idx);
            end
            
            % Tell the user what's happening
            app.loadbar.Message = 'Erzeuge Metadaten';
            app.loadbar.Value = 0.2;
            
            pdata = pdata.unflatten();
            
            % Tell the user what's happening
            app.loadbar.Message = 'Plotte Nachfolgerfunktion';
            app.loadbar.Value = 0.4;
            
            hdata = lhp.algo.stochastic.HarkData(result.s,pdata);
            hdata.plot(pdata, app.UIAxes3);
            
            % Plot the time taken
            app.Label.Text = sprintf("Ausfuehrungszeit (s): %f", result.time);
            app.Label_2.Text = sprintf("Algorithmus: %s", alg_name);
            
            % Tell the user what's happening
            app.loadbar.Message = 'Plotte Kosten';
            app.loadbar.Value = 0.6;
            
            % Plot the remaining costs
            HA = result.HA;
            WA = result.WA;
            TA = result.TA;
            K = result.K;
            
            x = categorical(["Harkarbeit", "Wegearbeit", "Transportarbeit", "Kosten"]);
            x = reordercats(x, ["Harkarbeit", "Wegearbeit", "Transportarbeit", "Kosten"]);
            y = [HA, WA, TA, K];
            
            b = bar(app.UIAxes, x, y);
%             xticklabels(app.UIAxes, x);
            % Add data tips to the bars
            xtips = b.XEndPoints;
            ytips = b.YEndPoints;
            labels = string(b.YData);
            text(app.UIAxes, xtips, ytips, labels, ...
                "HorizontalAlignment", "right", ...
                "VerticalAlignment", "middle", ...
                "Rotation", 90);
            
            % Tell the user what's happening
            app.loadbar.Message = 'Plotte Gartenmatrix';
            app.loadbar.Value = 0.8;
            
            % Make a plot
            % Clear any previous clutter
            delete(app.GartenPlotTab.Children);
            ax = axes(app.GartenPlotTab);
            % Make the plot and a custom colorbar
            pdata.Garden.plot(ax);
            
            % Kill the wait dialog
            app.loadbar.Message = 'Aktualisiere das UI';
            app.loadbar.Value = 1.0;
            drawnow();
            close(app.loadbar);
        end
        
        function updateTM(app)
            %% Callback to call whenever something about the TestManager changed.
            %
            %   These "changes" may include either changes to the Algorithms and
            %   Problems stored inside, or to the results table (i.e. new tests
            %   have been run).
            %
            %   The callback updates the Algorithm and ProblemData list view in
            %   the first tab of the GUI and makes sure that the table view in
            %   the GUI is refreshed, too.
            app.tm_tbl = app.tm.get_results();
            app.updateErgebnisSelection();
            app.updateAlgorithms();
            app.updateProblems();
        end
    end
    
    methods (Access = private, Static)
        function data = get_data(struct, op)
            % Get data from a given result 'struct' and apply 'op'.
            % Returns NaN if 'var' is no member of 'struct'.
            fnames = ["HA", "TA", "WA", "K", "time"];
            if ~isstruct(struct)
                % This is not a valid result structure. The test probably wasn't
                % run yet... Return a struct that contains all NaN
                data = builtin("struct", "HA", NaN, "TA", NaN, "WA", NaN, ...
                    "K", NaN, "time", NaN);
                return;
%                 for f_idx = 1:numel(fnames)
%                     data.(fnames(f_idx)) = NaN;
%                 end
            end
            
            fn = fieldnames(struct);
            for f_idx = 1:numel(fn)
                f = fn{f_idx};
                if ismember(f, fnames)
                    try
                        data.(f) = op([struct.(f)]);
                    catch
                        data.(f) = NaN;
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tm)
            arguments
                app (1, 1)
                tm (1, 1) lhp.utils.TestManager = lhp.utils.TestManager();
            end
            % Wir brauchen die ganzen Metadaten nicht, so lange keine Tests
            % gemacht werden.
            tm.flatten();
            
            % Eingabeargument abspeichern
            app.tm = tm;
            app.updateTM();
            
            % Callbacks definieren
            % Aktualisiere automatisch die Tabelle, wenn etwas gedrueckt wird.
            addlistener(app, 'tm_cur_tbl', 'PostSet', @(src, event)app.updateTable());
            addlistener(app, 'algorithms', 'PostSet', @(src, event)app.updateAlgorithms());
            addlistener(app, 'problems', 'PostSet', @(src, event)app.updateProblems());
            
            % Führe einige Callbacks direkt aus, um sicher zu stellen, dass alle
            % UI Elemente korrekt sind.
            app.DarstellungDropDownValueChanged([]);
        end

        % Selection changed function: ZuzeigendeDatenButtonGroup
        function ZuzeigendeDatenButtonGroupSelectionChanged(app, event)
            app.updateErgebnisSelection();
        end

        % Selection changed function: 
        % ZusammenfassenderDatenButtonGroup
        function ZusammenfassenderDatenButtonGroupSelectionChanged(app, event)
            app.updateErgebnisSelection();
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if isempty(event.Indices)
                return;
            elseif (size(indices, 1) > 1) || (indices(1, 2) < 4)
                % We only allow selecting whole columns. If the user selected an
                % area, do nothing.
                if any(indices(1, 1) ~= indices(:, 1))
                    % Invalid selection, don't plot.
                    return;
                end
                % Plot the best solution of this test.
                row_idx = indices(1, 1);
                [~, col_idx] = min(app.tm_cur_tbl{row_idx, 4:end});
                col_idx = col_idx + 3;
                % TODO: Update the selection in the UI
%                 app.UITable.
            else
                row_idx = indices(1, 1);
                col_idx = indices(1, 2);
            end
            
            % Check that selection isn't NaN
            if isnan(app.tm_cur_tbl{row_idx, col_idx})
                uialert(app.LaubharkproblemUIFigure, ...
                    "Einträge mit NaN sind nicht darstellbar!", ...
                    "Ungültige Auswahl");
                return;
            end
            
            % Launch the load dialog on the resutls tab.
            delete(app.loadbar);
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                'Title', 'Bitte warten', ...
                'Message', 'Daten werden geladen...');
            
            pdata = app.tm_cur_tbl.ProblemData{row_idx};
            app.makePdataTree(pdata, app.Tree);
            app.plotResults(row_idx, col_idx);
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            % We print output only if one node was selected
            if numel(selectedNodes) > 1
                return;
            end
            
            % We print output only if there is custom data in the node
            if isempty(selectedNodes.NodeData)
                return;
            end
            
            str = evalc('disp(selectedNodes.NodeData)');
            % Remove the custom Help and Docs links, because they break in the
            % GUI.
            str = regexprep(str, '\[\<.*\>\]', "");
            app.TextArea.Value = str;
        end

        % Button pushed function: NeuButton
        function NeuButtonPushed(app, event)
            % Add a new algorithm to the application.
            lhp.gui.Algorithm("Callback", @app.add_algorithm);
        end

        % Button pushed function: ndernButton
        function ndernButtonPushed(app, event)
            % Change an existing algorithm in the application.
            selection = app.Tree2.SelectedNodes;
            if numel(selection) > 1
                uialert(app.LaubharkproblemUIFigure, ...
                    "Aenderungen sind nur bei einzelnen Algorithmen moeglich!", ...
                    "Ungueltige Aenderung");
                return;
            end
            
%             alg = selection.NodeData;
%             alg_idx = selection.UserData;
            
            % TODO
            % Muss in dem GUI noch implementiert werden!
            %lhp.gui.Algorithm(alg, "Callback", @(x) app.add_algorithm(x, alg_idx));
        end

        % Button pushed function: LschenButton
        function LschenButtonPushed(app, event)
            % Delete an existing algorithm from the application
            selection = app.Tree2.SelectedNodes;
            if numel(selection) > 1
                uialert(app.LaubharkproblemUIFigure, ...
                    "Algorithmen koennen nur einzeln geloescht werden!", ...
                    "Auswahl ungueltig");
                return;
            end
            
            alg = selection.NodeData;
            % Remove from TestManager
            app.tm.remove_algorithm(alg.get_name());
            app.updateTM();
            % Clear the text area so it doesn't show text from a deleted
            % algorithm.
            app.TextArea.Value = "";
        end

        % Button pushed function: NeuButton_2
        function NeuButton_2Pushed(app, event)
            % Add a new problem to the application
            lhp.gui.ProblemData("Callback", @app.add_problem);
        end

        % Button pushed function: ndernButton_2
        function ndernButton_2Pushed(app, event)
            % Modify an existing problem in the application.
            selection = app.ListBox.Value;
            if numel(selection) > 1
                uialert(app.LaubharkproblemUIFigure, ...
                    "Aenderungen sind nur bei einzelnen Problemen moeglich!", ...
                    "Ungueltige Aenderung");
                return;
            end
            
            pdata = app.tm_tbl.ProblemData{app.tm_tbl.TestCaseID == selection};
            lhp.gui.ProblemData("ProblemData", pdata, ...
                "Callback", @(x) app.add_problem(x, selection));
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % Executed when the user selects a TestCase from the list in the
            % first GUI tab.
            value = app.tm_tbl.ProblemData{app.tm_tbl.TestCaseID == app.ListBox.Value};
            app.makePdataTree(value, app.Tree3);
            
            % Make a plot
            % Clear any previous clutter
            delete(app.GartenPlotTab.Children);
            ax = axes(app.GartenPlotTab);
            % Make the plot and a custom colorbar
            value.Garden.plot(ax);
        end

        % Button pushed function: LschenButton_2
        function LschenButton_2Pushed(app, event)
            % Delete an existing problem from the application
            selection = app.ListBox.Value;
            if numel(selection) > 1
                uialert(app.LaubharkproblemUIFigure, ...
                    "Algorithmen koennen nur einzeln geloescht werden!", ...
                    "Auswahl ungueltig");
                return;
            end
            
            % Update in the TestManager
            app.tm.remove_test_case(selection);
            app.updateTM();
            % Clear the tree view so it doesn't show info from deleted problems
            delete(app.Tree3.Children);
        end

        % Button pushed function: TestsdurchfhrenButton
        function TestsdurchfhrenButtonPushed(app, event)
            % Create a loading bar
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                "Title", "Bitte warten...", ...
                "Cancelable", "off");
            
            app.loadbar.Message = "Erzeuge fehlende Metadaten";
            app.loadbar.Indeterminate = "on";
            app.tm.unflatten();
            
            app.loadbar.Message = "Starte parallel-pool";
            app.loadbar.Indeterminate = "off";
            app.tm.runAllTests("UI", app.loadbar);
            
            app.loadbar.Message = "Aktualisiere das UI";
            % Switch view to the results tab
            app.TabGroup.SelectedTab = app.TestManagerTab;
            app.updateTM();
            drawnow();
            % Remove all metadata to reduce space usage
            app.tm.flatten();
            
            delete(app.loadbar);
        end

        % Menu selected function: AusdemWorkspaceMenu
        function AusdemWorkspaceMenuSelected(app, event)
            %% Import TestManager from base workspace.
            %
            %   Asks the user to provide a variable name to a variable in the
            %   base workspace. If the variable exists and is a scalar of type
            %   'TestManager', it is imported into the app.
            user_var = inputdlg(...
                "Name der Variablen im Base Workspace:", ...
                "Variable auswaehlen", ...
                [1, 40]);
            
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                "Title", "Variable wird importiert", ...
                "Indeterminate", "on");
            app.loadbar.Message = "Pruefe gueltigkeit der Variablen";
            user_var = user_var{1};
            base_vars = evalin('base', 'who');
            exists = cellfun(@(x) strcmp(x, user_var), base_vars);
            
            if ~any(exists)
                uialert(app.LaubharkproblemUIFigure, ...
                    sprintf("Variable %s existiert nicht im base workspace!", user_var), ...
                    "Variable nicht gefunden", ...
                    'Icon', 'error');
                return;
            end
            % user_var is valid!
            var = evalin('base', user_var);
            if isa(var, 'lhp.utils.TestManager') && isscalar(var)
                % Make sure we have all metadata.
                app.loadbar.Message = "Erzeuge notwendige Metadaten";
                var.unflatten();
                app.tm = var;
                app.updateTM();
                delete(app.loadbar);
            else
                uialert(app.LaubharkproblemUIFigure, ...
                    "Variable '%s' muss ein Skalar vom Typ 'TestManager' sein!", ...
                    "Ungueltiger Datentyp", ...
                    "Icon", "error");
                return;
            end
        end

        % Menu selected function: AuseinerDateiMenu
        function AuseinerDateiMenuSelected(app, event)
            [file, path] = uigetfile(...
                {'*.mat', 'MAT-Dateien (*.mat)'}, ...
                 "MAT-Datei mit TestManager auswaehlen");
            filename = string(path) + string(file);
            
             % Make sure it's a non-binary file
            if ~isfile(filename)
                uialert(app.LaubharkproblemUIFigure, ...
                    "Datei '%s' existiert nicht!", "Datei nicht gefunden");
                return;
            end
            
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                "Title", "Datei wird importiert...", ...
                "Indeterminate", "on");
            [~, ~, extension] = fileparts(filename);
            try
                switch (extension)
                    case {'.mat'}
                        app.loadbar.Message = "Lade Datei in den Speicher";
                        mf = matfile(filename);
                        app.loadbar.Message = "Suche Variablen in der Datei";
                        mf_vars = whos(mf);
                        
                        tms = strcmp(arrayfun(@(struct) string(struct.class), mf_vars), "lhp.utils.TestManager");
                        if any(tms)
                            % Found object of type TestManager
                            var_index = find(tms, 1);
                            varname = mf_vars(var_index).name;
                            
                            if any(mf_vars(var_index).size ~= 1)
                                uialert(app.LaubharkproblemUIFigure, ...
                                    "Objekt von Typ 'TestManager' in Datei '%s' ist nicht skalar!", ...
                                    "Fehler", ...
                                    "Icon", "error");
                                return;
                            end
                            
                            app.tm = mf.(varname);
                            app.loadbar.Message = "Extrahiere Metadaten";
                            % Reconstruct all metadata
                            app.tm.unflatten();
                            app.updateTM();
                            % Kill the loading bar
                            delete(app.loadbar);
                            return;
                        end
                    otherwise
                        uialert(app.ProblemDataerzeugenUIFigure, ...
                            "Dateiendung '%s' wird nicht unterstuetzt!", "Datei ungueltig");
                        return;
                end
            catch ME
                delete(app.loadbar);
                uialert(app.LaubharkproblemUIFigure, ...
                    "Fehler", ME.message);
                return;
            end
        end

        % Menu selected function: IneineDateiMenu
        function IneineDateiMenuSelected(app, event)
            [file, path] = uiputfile('*.mat', ...
                "Speicherort festlegen");
            filename = string(path) + string(file);
            tm = app.tm;   %#ok
            tm.flatten();  %#ok
            save(filename, "tm", '-v7.3');
        end

        % Menu selected function: IndenWorkspaceMenu
        function IndenWorkspaceMenuSelected(app, event)
            varname = inputdlg(...
                "Name der Variablen, die erzeugt werden soll", ...
                "Variable festlegen", ...
                [1, 40]);
            assignin("base", varname{1}, app.tm);
        end

        % Value changed function: DarstellungDropDown
        function DarstellungDropDownValueChanged(app, event)
            value = app.DarstellungDropDown.Value;
            
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                "Title", "Ansicht wird aktualisiert...", ...
                "Indeterminate", "on");
            
            switch value
                case "Übersicht"
                    % Default: The 'TestManager' Tab shows an overview of all
                    % test results, at the cost of always only showing one
                    % metric for comparison.
                    app.ZuzeigendeDatenButtonGroup.Visible = true;
                    app.bersichtPanel.Visible = true;
                    app.TestauswahlPanel.Visible = false;
                    app.Panel_6.Visible = false;
                    % Update Tab View
                    app.TabGroup2.SelectedTab = app.bersichtTab;
                    % Otherwise Matlab may hiccup trying to draw everything
                    pause(0.1);
                    drawnow();
                    pause(0.1);
                    % Let the function handle all the rest.
                    app.updateErgebnisSelection();
                    
                case "Pro Test"
                    % Alternative view: The 'TestManager' Tab shows an overview
                    % of the test results **for one test case only**. In this
                    % view type, all metrics are visible, at the cost of always
                    % only showing one test for comparison.
                    app.ZuzeigendeDatenButtonGroup.Visible = false;
                    app.bersichtPanel.Visible = false;
                    app.TestauswahlPanel.Visible = true;
                    app.Panel_6.Visible = true;
                    % Update Tab View
                    app.TabGroup2.SelectedTab = app.ProTestTab;
                    % Otherwise Matlab may hiccup trying to draw everything
                    pause(0.1);
                    drawnow();
                    pause(0.1);
                    
                    % Populate the TestID drop down
                    app.TestIDDropDown.Items = string(app.tm_tbl.TestCaseID);
                    
                    % Call the DropDown callback.
                    % This will also take care of updating the view for us.
                    app.TestIDDropDownValueChanged([]);
                otherwise
                    error("Unbekannter Darstellungstyp '%s'", value);
            end
            
            % Make sure all draw events are processed. Otherwise they may be
            % left dangling, sometimes causing matlab to hiccup and hang
            % indefinitely.
            pause(0.5);
            drawnow();
            pause(0.5);
            
            delete(app.loadbar);
        end

        % Value changed function: TestIDDropDown
        function TestIDDropDownValueChanged(app, event)
            app.updateErgebnisSelection();
            
            % Update the PData display
            testid = str2double(app.TestIDDropDown.Value);
            if ~isnan(testid) && ~isempty(testid)
                pdata = app.tm_tbl.ProblemData(testid == app.tm_tbl.TestCaseID);
                app.makePdataTree(pdata{1}, app.Tree);
            end
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            
            if isempty(event.Indices)
                % No selection
                return;
            elseif (size(indices, 1) > 1)
                % We only allow selecting whole rows. If the user selected an
                % area, do nothing.
                if any(indices(1, 1) ~= indices(:, 1))
                    % Invalid selection, don't plot.
                    return;
                end
            end
            % Id of the test that's selected
            row_idx = str2double(app.TestIDDropDown.Value);
            % For the col index, mind that the table is sorted!!
            col_idx = find(cellfun(@(name) isequal(...
                name, app.tm_cur_tbl.Properties.RowNames{indices(1, 1)}), ...
                app.tm_tbl.Properties.VariableNames));
            
            % Check that selection isn't NaN
            if any(isnan(app.tm_cur_tbl{indices(1, 1), :}))
                uialert(app.LaubharkproblemUIFigure, ...
                    "Einträge mit NaN sind nicht darstellbar!", ...
                    "Ungültige Auswahl");
                return;
            end
            
            % Launch the load dialog on the resutls tab.
            delete(app.loadbar);
            app.loadbar = uiprogressdlg(app.LaubharkproblemUIFigure, ...
                'Title', 'Bitte warten', ...
                'Message', 'Daten werden geladen...');
            
            app.plotResults(row_idx, col_idx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaubharkproblemUIFigure = uifigure('Visible', 'off');
            app.LaubharkproblemUIFigure.Name = 'Laubharkproblem';
            app.DateiMenu = uimenu(app.LaubharkproblemUIFigure);
            app.DateiMenu.Text = 'Datei';
            app.ImportMenu = uimenu(app.DateiMenu);
            app.ImportMenu.Accelerator = 'i';
            app.ImportMenu.Text = 'Import';
            app.AusdemWorkspaceMenu = uimenu(app.ImportMenu);
            app.AusdemWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @AusdemWorkspaceMenuSelected, true);
            app.AusdemWorkspaceMenu.Text = 'Aus dem Workspace';
            app.AuseinerDateiMenu = uimenu(app.ImportMenu);
            app.AuseinerDateiMenu.MenuSelectedFcn = createCallbackFcn(app, @AuseinerDateiMenuSelected, true);
            app.AuseinerDateiMenu.Text = 'Aus einer Datei';
            app.ExportMenu = uimenu(app.DateiMenu);
            app.ExportMenu.Accelerator = 'e';
            app.ExportMenu.Text = 'Export';
            app.IndenWorkspaceMenu = uimenu(app.ExportMenu);
            app.IndenWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @IndenWorkspaceMenuSelected, true);
            app.IndenWorkspaceMenu.Text = 'In den Workspace';
            app.IneineDateiMenu = uimenu(app.ExportMenu);
            app.IneineDateiMenu.MenuSelectedFcn = createCallbackFcn(app, @IneineDateiMenuSelected, true);
            app.IneineDateiMenu.Text = 'In eine Datei';
            app.TabGroup = uitabgroup(app.LaubharkproblemUIFigure);
            app.TestvorbereitungTab = uitab(app.TabGroup);
            app.TestvorbereitungTab.Title = 'Testvorbereitung';
            app.GridLayout3 = uigridlayout(app.TestvorbereitungTab);
            app.Tree2 = uitree(app.GridLayout3);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.Tree2.Tooltip = {'Liste aller konfigurierten Algorithmen. Wählen Sie einen Eintrag aus, um ihn im Fenster rechts daneben genauer inspizieren, oder mit den Knöpfen weiter unten bearbeiten zu können.'};
            app.DeterministischeHeuristikenNode = uitreenode(app.Tree2);
            app.DeterministischeHeuristikenNode.Text = 'Deterministische Heuristiken';
            app.StochastischeHeuristikenNode = uitreenode(app.Tree2);
            app.StochastischeHeuristikenNode.Text = 'Stochastische Heuristiken';
            app.AlgorithmenLabel = uilabel(app.GridLayout3);
            app.AlgorithmenLabel.Text = 'Algorithmen';
            app.ProblemDataLabel = uilabel(app.GridLayout3);
            app.ProblemDataLabel.Text = 'ProblemData';
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {'Informationen zum ausgewählten Algorithmus'};
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'Wählen Sie einen Eintrag aus dieser Liste aus, um ihn im linken Fenster genauer inspizieren zu können. Den Garten-Plot des ausgewählten Problems finden Sie dann im "Garten-Plot" Tab.'};
            app.ListBox.Value = {};
            app.Panel = uipanel(app.GridLayout3);
            app.NeuButton = uibutton(app.Panel, 'push');
            app.NeuButton.ButtonPushedFcn = createCallbackFcn(app, @NeuButtonPushed, true);
            app.NeuButton.Tooltip = {'Öffnet ein Dialogfenster zum Auswählen eines neuen Algorithmus'};
            app.NeuButton.Text = 'Neu';
            app.ndernButton = uibutton(app.Panel, 'push');
            app.ndernButton.ButtonPushedFcn = createCallbackFcn(app, @ndernButtonPushed, true);
            app.ndernButton.Enable = 'off';
            app.ndernButton.Tooltip = {'WORK IN PROGRESS:'; 'Öffnet ein Dialogfenster zum Ändern von Parametern in ausgewählten Algorithmen'};
            app.ndernButton.Text = 'Ändern';
            app.LschenButton = uibutton(app.Panel, 'push');
            app.LschenButton.ButtonPushedFcn = createCallbackFcn(app, @LschenButtonPushed, true);
            app.LschenButton.Tooltip = {'Löscht die ausgewählten Algorithmen'};
            app.LschenButton.Text = 'Löschen';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.NeuButton_2 = uibutton(app.Panel_2, 'push');
            app.NeuButton_2.ButtonPushedFcn = createCallbackFcn(app, @NeuButton_2Pushed, true);
            app.NeuButton_2.Tooltip = {'Öffnet ein Dialogfenster zum Definieren eines neuen Problems'};
            app.NeuButton_2.Text = 'Neu';
            app.ndernButton_2 = uibutton(app.Panel_2, 'push');
            app.ndernButton_2.ButtonPushedFcn = createCallbackFcn(app, @ndernButton_2Pushed, true);
            app.ndernButton_2.Tooltip = {'Öffnet ein Dialogfenster zum Ändern von Parametern in ausgewählten Problemen'};
            app.ndernButton_2.Text = 'Ändern';
            app.LschenButton_2 = uibutton(app.Panel_2, 'push');
            app.LschenButton_2.ButtonPushedFcn = createCallbackFcn(app, @LschenButton_2Pushed, true);
            app.LschenButton_2.Tooltip = {'Löscht die ausgewählten Probleme'};
            app.LschenButton_2.Text = 'Löschen';
            app.Tree3 = uitree(app.GridLayout3);
            app.Tree3.Tooltip = {'Informationen zum ausgewählten Problem/Test Case.'};
            app.TestsdurchfhrenButton = uibutton(app.GridLayout3, 'push');
            app.TestsdurchfhrenButton.ButtonPushedFcn = createCallbackFcn(app, @TestsdurchfhrenButtonPushed, true);
            app.TestsdurchfhrenButton.Tooltip = {'Startet alle noch nicht durchgeführten Tests. Je nach Auswahl kann das sehr lange dauern.'};
            app.TestsdurchfhrenButton.Text = 'Tests durchführen';
            app.TestManagerTab = uitab(app.TabGroup);
            app.TestManagerTab.Title = 'TestManager';
            app.GridLayout5 = uigridlayout(app.TestManagerTab);
            app.EinstellungenPanel = uipanel(app.GridLayout5);
            app.EinstellungenPanel.Title = 'Einstellungen';
            app.GridLayout4 = uigridlayout(app.EinstellungenPanel);
            app.Tree = uitree(app.GridLayout4);
            app.Tree.Tooltip = {'Parameter des ProblemData-Objektes in der ausgewählten Zeile.'};
            app.ZusammenfassenderDatenButtonGroup = uibuttongroup(app.GridLayout4);
            app.ZusammenfassenderDatenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ZusammenfassenderDatenButtonGroupSelectionChanged, true);
            app.ZusammenfassenderDatenButtonGroup.Tooltip = {'Die stochastischen Heuristiken laufen in einer Monte-Carlo-Somulation und erzeugen daher mehrere Ergebnisse, die aber nicht alle in der Tabelle angezeigt werden können. Hier wird ausgewählt, wie die Ergebnisse zusammengefasst werden.'};
            app.ZusammenfassenderDatenButtonGroup.Title = 'Zusammenfassen der Daten';
            app.MittelwertButton = uitogglebutton(app.ZusammenfassenderDatenButtonGroup);
            app.MittelwertButton.Text = 'Mittelwert';
            app.MittelwertButton.Value = true;
            app.MedianButton = uitogglebutton(app.ZusammenfassenderDatenButtonGroup);
            app.MedianButton.Text = 'Median';
            app.MinimumButton = uitogglebutton(app.ZusammenfassenderDatenButtonGroup);
            app.MinimumButton.Text = 'Minimum';
            app.MaximumButton_2 = uitogglebutton(app.ZusammenfassenderDatenButtonGroup);
            app.MaximumButton_2.Text = 'Maximum';
            app.Panel_3 = uipanel(app.GridLayout4);
            app.DarstellungDropDownLabel = uilabel(app.Panel_3);
            app.DarstellungDropDownLabel.Text = 'Darstellung';
            app.DarstellungDropDown = uidropdown(app.Panel_3);
            app.DarstellungDropDown.Items = {'Übersicht', 'Pro Test'};
            app.DarstellungDropDown.ValueChangedFcn = createCallbackFcn(app, @DarstellungDropDownValueChanged, true);
            app.DarstellungDropDown.Value = 'Übersicht';
            app.Panel_5 = uipanel(app.GridLayout4);
            app.ZuzeigendeDatenButtonGroup = uibuttongroup(app.Panel_5);
            app.ZuzeigendeDatenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ZuzeigendeDatenButtonGroupSelectionChanged, true);
            app.ZuzeigendeDatenButtonGroup.Tooltip = {'Für jeden Algorithmus werden mehrere Daten gespeichert. Hier wird ausgewählt, welche in der Tabellenansicht sichtbar sein sollen.'; ''};
            app.ZuzeigendeDatenButtonGroup.Title = 'Zu zeigende Daten';
            app.GesamtkostenButton = uitogglebutton(app.ZuzeigendeDatenButtonGroup);
            app.GesamtkostenButton.Text = 'Gesamtkosten';
            app.GesamtkostenButton.Value = true;
            app.HarkkostenButton = uitogglebutton(app.ZuzeigendeDatenButtonGroup);
            app.HarkkostenButton.Text = 'Harkkosten';
            app.WegekostenButton = uitogglebutton(app.ZuzeigendeDatenButtonGroup);
            app.WegekostenButton.Text = 'Wegekosten';
            app.TransportkostenButton = uitogglebutton(app.ZuzeigendeDatenButtonGroup);
            app.TransportkostenButton.Text = 'Transportkosten';
            app.AusfhrungszeitsButton_2 = uitogglebutton(app.ZuzeigendeDatenButtonGroup);
            app.AusfhrungszeitsButton_2.Text = 'Ausführungszeit (s)';
            app.TestauswahlPanel = uipanel(app.GridLayout4);
            app.TestauswahlPanel.Tooltip = {'Auswahl des anzuzeigenden Tests.'};
            app.TestauswahlPanel.Title = 'Testauswahl';
            app.TestIDDropDownLabel = uilabel(app.TestauswahlPanel);
            app.TestIDDropDownLabel.Text = 'Test ID';
            app.TestIDDropDown = uidropdown(app.TestauswahlPanel);
            app.TestIDDropDown.Items = {};
            app.TestIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TestIDDropDownValueChanged, true);
            app.TestIDDropDown.Value = {};
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.bersichtTab = uitab(app.TabGroup2);
            app.bersichtTab.Title = 'Übersicht';
            app.bersichtPanel = uipanel(app.bersichtTab);
            app.UITable = uitable(app.bersichtPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Wird eine Zelle ausgewählt, wird das Ergebnis im Tab "Ergebnisse" geplottet. Wird einer ganze Zeile ausgewählt, wird automatisch das Ergebnis des Algorithmus mit dem kleinsten numerischen Wert (beste Lösung) geplottet.'; ''; 'Noch nicht ausgeführte Tests haben den Wert "NaN".'};
            app.ProTestTab = uitab(app.TabGroup2);
            app.ProTestTab.Title = 'Pro Test';
            app.Panel_6 = uipanel(app.ProTestTab);
            app.GridLayout6 = uigridlayout(app.Panel_6);
            app.UITable2 = uitable(app.GridLayout6);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.UITable2.Tooltip = {'Wählen sie eine Zeile oder Zelle aus, um das jeweilge Ergebnis im "Ergebnisse" Tab grafisch darzustellen.'};
            app.UIAxes2 = uiaxes(app.GridLayout6);
            title(app.UIAxes2, 'Algorithmenkosten')
            xlabel(app.UIAxes2, 'Algorithmus')
            ylabel(app.UIAxes2, 'Kosten')
            app.ErgebnisseTab = uitab(app.TabGroup);
            app.ErgebnisseTab.Title = 'Ergebnisse';
            app.GridLayout = uigridlayout(app.ErgebnisseTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Nachfolgerfunktion')
            app.GartenPlotTab = uitab(app.TabGroup);
            app.GartenPlotTab.Title = 'Garten-Plot';
            % Show the figure after all components are created
            app.LaubharkproblemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaubharkproblemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaubharkproblemUIFigure)
        end
    end
end